classdef DSCmapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DSCmapUIFigure                matlab.ui.Figure
        Hyperlink                     matlab.ui.control.Hyperlink
        TabGroup4                     matlab.ui.container.TabGroup
        ArterialInputFunctionTab      matlab.ui.container.Tab
        YViewField                    matlab.ui.control.NumericEditField
        YViewFieldLabel               matlab.ui.control.Label
        XViewField                    matlab.ui.control.NumericEditField
        XViewFieldLabel               matlab.ui.control.Label
        ConcInputFunctionPlot         matlab.ui.control.UIAxes
        ArterialInputFunctionPlot     matlab.ui.control.UIAxes
        FitParametersTab              matlab.ui.container.Tab
        CBFfitMethodButtonGroup       matlab.ui.container.ButtonGroup
        oSVDLabel                     matlab.ui.control.Label
        cSVDLabel                     matlab.ui.control.Label
        SVDLabel                      matlab.ui.control.Label
        RhoEditField                  matlab.ui.control.NumericEditField
        RhoEditFieldLabel             matlab.ui.control.Label
        KhEditField                   matlab.ui.control.NumericEditField
        KhEditFieldLabel              matlab.ui.control.Label
        oSVDButton                    matlab.ui.control.RadioButton
        cSVDButton                    matlab.ui.control.RadioButton
        SVDButton                     matlab.ui.control.RadioButton
        AIFSettingsPanel              matlab.ui.container.Panel
        QuadraticCheckBoxLabel        matlab.ui.control.Label
        QuadraticCheckBox             matlab.ui.control.CheckBox
        MaxAIFpixelsEditField         matlab.ui.control.NumericEditField
        MaxAIFpixelsEditFieldLabel    matlab.ui.control.Label
        ShortAxisEditField            matlab.ui.control.NumericEditField
        ShortAxisEditFieldLabel       matlab.ui.control.Label
        LongAxisEditField             matlab.ui.control.NumericEditField
        LongAxisEditFieldLabel        matlab.ui.control.Label
        DataSelectionPanel            matlab.ui.container.Panel
        FirstDynamicEditField         matlab.ui.control.NumericEditField
        FirstDynamicEditFieldLabel    matlab.ui.control.Label
        FitEvaluationTab              matlab.ui.container.Tab
        ConcentrationPlot             matlab.ui.control.UIAxes
        ResidualPlot                  matlab.ui.control.UIAxes
        TabGroup2                     matlab.ui.container.TabGroup
        RawImagesTab                  matlab.ui.container.Tab
        RawImage                      matlab.ui.control.Image
        ImageWindowLevelPanel         matlab.ui.container.Panel
        AutoLWButton                  matlab.ui.control.StateButton
        WindowEditFieldLabel          matlab.ui.control.Label
        LevelEditFieldLabel           matlab.ui.control.Label
        LevelSlider                   matlab.ui.control.Slider
        LevelEditField                matlab.ui.control.NumericEditField
        WindowSlider                  matlab.ui.control.Slider
        WindowEditField               matlab.ui.control.NumericEditField
        MaskSettingsPanel             matlab.ui.container.Panel
        thresholdEditField            matlab.ui.control.NumericEditField
        thresholdEditFieldLabel       matlab.ui.control.Label
        MaskSizeEditField             matlab.ui.control.NumericEditField
        UndoButton                    matlab.ui.control.Button
        OpenButton                    matlab.ui.control.Button
        CloseButton                   matlab.ui.control.Button
        DilateButton                  matlab.ui.control.Button
        ErodeButton                   matlab.ui.control.Button
        ThresholdSlider               matlab.ui.control.Slider
        ImageSelectionPanel           matlab.ui.container.Panel
        DynamicsEditFieldLabel        matlab.ui.control.Label
        SlicesEditFieldLabel          matlab.ui.control.Label
        DynamicsSlider                matlab.ui.control.Slider
        DynamicsEditField             matlab.ui.control.NumericEditField
        SlicesSlider                  matlab.ui.control.Slider
        SlicesEditField               matlab.ui.control.NumericEditField
        ImageLabel                    matlab.ui.control.Label
        RawDataFig                    matlab.ui.control.UIAxes
        SequenceParametersTab         matlab.ui.container.Tab
        SliceThicknessViewField       matlab.ui.control.NumericEditField
        SliceThicknessViewFieldLabel  matlab.ui.control.Label
        SlicesViewField               matlab.ui.control.NumericEditField
        SlicesViewFieldLabel          matlab.ui.control.Label
        dimyViewField                 matlab.ui.control.NumericEditField
        dimyViewFieldLabel            matlab.ui.control.Label
        dimxViewField                 matlab.ui.control.NumericEditField
        dimxViewFieldLabel            matlab.ui.control.Label
        FOVViewField                  matlab.ui.control.NumericEditField
        FOVViewFieldLabel             matlab.ui.control.Label
        AspectRatioViewField          matlab.ui.control.NumericEditField
        AspectRatioViewFieldLabel     matlab.ui.control.Label
        NRDynamicsViewField           matlab.ui.control.NumericEditField
        NRDynamicsViewFieldLabel      matlab.ui.control.Label
        FlipAngleViewField            matlab.ui.control.NumericEditField
        FlipAngleViewFieldLabel       matlab.ui.control.Label
        TEViewField                   matlab.ui.control.NumericEditField
        TEViewFieldLabel              matlab.ui.control.Label
        TRViewField                   matlab.ui.control.NumericEditField
        TRViewFieldLabel              matlab.ui.control.Label
        DicomFolderViewField          matlab.ui.control.EditField
        DicomFolderViewFieldLabel     matlab.ui.control.Label
        AppSettingsTab                matlab.ui.container.Tab
        ColorsButtonGroup             matlab.ui.container.ButtonGroup
        BlackWhiteButton              matlab.ui.control.RadioButton
        ClassicButton                 matlab.ui.control.RadioButton
        ColorsLabel                   matlab.ui.control.Label
        AutoSoundsCheckBoxLabel       matlab.ui.control.Label
        AutoSoundsCheckBox            matlab.ui.control.CheckBox
        SoundsLabel                   matlab.ui.control.Label
        TabGroup3                     matlab.ui.container.TabGroup
        rCBVrCBFTab                   matlab.ui.container.Tab
        CBFColorMapButtonGroup        matlab.ui.container.ButtonGroup
        CBFScaleAutoButton            matlab.ui.control.Button
        CBFScaleMinEditField          matlab.ui.control.NumericEditField
        CBFScaleMinLabel              matlab.ui.control.Label
        CBFScaleMaxLabel              matlab.ui.control.Label
        CBFScaleEditField             matlab.ui.control.NumericEditField
        CBFnaviaButton                matlab.ui.control.RadioButton
        CBFparulaButton               matlab.ui.control.RadioButton
        CBFwinterButton               matlab.ui.control.RadioButton
        CBFlipariButton               matlab.ui.control.RadioButton
        CBFgrayButton                 matlab.ui.control.RadioButton
        CBVColorMapButtonGroup        matlab.ui.container.ButtonGroup
        CBVScaleAutoButton            matlab.ui.control.Button
        CBVScaleMinEditField          matlab.ui.control.NumericEditField
        CBVScaleMinLabel              matlab.ui.control.Label
        CBVScaleMaxLabel              matlab.ui.control.Label
        CBVScaleEditField             matlab.ui.control.NumericEditField
        CBVnaviaButton                matlab.ui.control.RadioButton
        CBVparulaButton               matlab.ui.control.RadioButton
        CBVwinterButton               matlab.ui.control.RadioButton
        CBVlipariButton               matlab.ui.control.RadioButton
        CBVgrayButton                 matlab.ui.control.RadioButton
        MapImageSelectionPanel1       matlab.ui.container.Panel
        MapSlicesEditField1           matlab.ui.control.NumericEditField
        MapSlicesEditFieldLabel1      matlab.ui.control.Label
        MapSlicesSlider1              matlab.ui.control.Slider
        CBVImage                      matlab.ui.control.Image
        rCBF1sLabel                   matlab.ui.control.Label
        rCBVLabel                     matlab.ui.control.Label
        CBFImage                      matlab.ui.control.Image
        CBFMapFig                     matlab.ui.control.UIAxes
        CBVMapFig                     matlab.ui.control.UIAxes
        MTTTTPTab                     matlab.ui.container.Tab
        TTPColorMapButtonGroup        matlab.ui.container.ButtonGroup
        TTPScaleAutoButton            matlab.ui.control.Button
        TTPScaleMinEditField          matlab.ui.control.NumericEditField
        TTPScaleMinLabel              matlab.ui.control.Label
        TTPScaleMaxLabel              matlab.ui.control.Label
        TTPScaleEditField             matlab.ui.control.NumericEditField
        TTPnaviaButton                matlab.ui.control.RadioButton
        TTPparulaButton               matlab.ui.control.RadioButton
        TTPwinterButton               matlab.ui.control.RadioButton
        TTPlipariButton               matlab.ui.control.RadioButton
        TTPgrayButton                 matlab.ui.control.RadioButton
        MTTColorMapButtonGroup        matlab.ui.container.ButtonGroup
        MTTScaleAutoButton            matlab.ui.control.Button
        MTTScaleMinEditField          matlab.ui.control.NumericEditField
        MTTScaleMinLabel              matlab.ui.control.Label
        MTTScaleMaxLabel              matlab.ui.control.Label
        MTTScaleEditField             matlab.ui.control.NumericEditField
        MTTnaviaButton                matlab.ui.control.RadioButton
        MTTparulaButton               matlab.ui.control.RadioButton
        MTTwinterButton               matlab.ui.control.RadioButton
        MTTlipariButton               matlab.ui.control.RadioButton
        MTTgrayButton                 matlab.ui.control.RadioButton
        TTPImage                      matlab.ui.control.Image
        MTTImage                      matlab.ui.control.Image
        MapImageSelectionPanel2       matlab.ui.container.Panel
        MapSlicesEditField2           matlab.ui.control.NumericEditField
        MapSlicesEditFieldLabel2      matlab.ui.control.Label
        MapSlicesSlider2              matlab.ui.control.Slider
        TTPsLabel                     matlab.ui.control.Label
        MTTsLabel                     matlab.ui.control.Label
        TTPMapFig                     matlab.ui.control.UIAxes
        MTTMapFig                     matlab.ui.control.UIAxes
        rCBVlcK2Tab                   matlab.ui.container.Tab
        K2ColorMapButtonGroup         matlab.ui.container.ButtonGroup
        K2ScaleAutoButton             matlab.ui.control.Button
        K2ScaleMinEditField           matlab.ui.control.NumericEditField
        K2ScaleMinLabel               matlab.ui.control.Label
        K2ScaleMaxLabel               matlab.ui.control.Label
        K2ScaleEditField              matlab.ui.control.NumericEditField
        K2naviaButton                 matlab.ui.control.RadioButton
        K2parulaButton                matlab.ui.control.RadioButton
        K2winterButton                matlab.ui.control.RadioButton
        K2lipariButton                matlab.ui.control.RadioButton
        K2grayButton                  matlab.ui.control.RadioButton
        CBVlcColorMapButtonGroup      matlab.ui.container.ButtonGroup
        CBVlcScaleAutoButton          matlab.ui.control.Button
        CBVlcScaleMinEditField        matlab.ui.control.NumericEditField
        CBVlcScaleMinLabel            matlab.ui.control.Label
        CBVlcScaleMaxLabel            matlab.ui.control.Label
        CBVlcScaleEditField           matlab.ui.control.NumericEditField
        CBVlcNaviaButton              matlab.ui.control.RadioButton
        CBVlcparulaButton             matlab.ui.control.RadioButton
        CBVlcwinterButton             matlab.ui.control.RadioButton
        CBVllipariButton              matlab.ui.control.RadioButton
        CBVlcgrayButton               matlab.ui.control.RadioButton
        MapImageSelectionPanel3       matlab.ui.container.Panel
        MapSlicesEditField3           matlab.ui.control.NumericEditField
        MapSlicesEditFieldLabel3      matlab.ui.control.Label
        MapSlicesSlider3              matlab.ui.control.Slider
        CBVlcImage                    matlab.ui.control.Image
        K2Label                       matlab.ui.control.Label
        rCBVlcLabel                   matlab.ui.control.Label
        K2Image                       matlab.ui.control.Image
        K2MapFig                      matlab.ui.control.UIAxes
        CBVlcMapFig                   matlab.ui.control.UIAxes
        TabGroup1                     matlab.ui.container.TabGroup
        DashboardTab                  matlab.ui.container.Tab
        EstimatedFitTimeViewField     matlab.ui.control.EditField
        EstimatedRegTimeViewField     matlab.ui.control.EditField
        AbortRegButton                matlab.ui.control.Button
        RegistrationDropDown          matlab.ui.control.DropDown
        RegProgressLabel              matlab.ui.control.Label
        RegProgressGauge              matlab.ui.control.LinearGauge
        Lamp2                         matlab.ui.control.Lamp
        RegisterButton                matlab.ui.control.Button
        GIFButton                     matlab.ui.control.Button
        Lamp6                         matlab.ui.control.Lamp
        FitProgressLabel              matlab.ui.control.Label
        DCMNIFTIButton                matlab.ui.control.Button
        ExitButton                    matlab.ui.control.Button
        Lamp5                         matlab.ui.control.Lamp
        FitProgressGauge              matlab.ui.control.LinearGauge
        Lamp4                         matlab.ui.control.Lamp
        FitDSCButton                  matlab.ui.control.Button
        Lamp3                         matlab.ui.control.Lamp
        MaskButton                    matlab.ui.control.Button
        Lamp1                         matlab.ui.control.Lamp
        LoadDCMfileButton             matlab.ui.control.Button
        MRSImage                      matlab.ui.control.Image
        StatusLabel                   matlab.ui.control.Label
        StatusImage                   matlab.ui.control.Image
        BackgroundBox                 matlab.ui.control.TextArea
        MessageBox                    matlab.ui.control.TextArea
        TulpLogo                      matlab.ui.control.Image
        Credits                       matlab.ui.control.Label
        DSCmappingLabel               matlab.ui.control.Label
    end


    % --------------------------------------------------------------------------------------
    %
    %   DSCmapp
    %
    %   Dynamic susceptibility contrast mapping of MR Solutions DICOM data
    %
    %   Gustav Strijkers
    %   Amsterdam UMC
    %   g.j.strijkers@amsterdamumc.nl
    %   September 2026
    %
    %   How the app is put together:
    %
    %     DSCmapp.mlapp + gui/   widgets, callbacks and plotting; gui/adapters holds
    %                            the only code that reads a widget or holds the app
    %     @dscData               the dataset the app holds as app.td: the DICOM
    %                            headers, the dynamics, the mask, the AIF, the maps,
    %                            and the import that fills them
    %     +dscmapp/              import and export, registration, the AIF search and
    %                            the fit around the DSC-MRI toolbox, colour and display
    %                            helpers
    %     functions/             third party only: the DSC-MRI toolbox, dicm2nii and
    %                            the elastix tools; see THIRD-PARTY-NOTICES.md
    %
    %   A series is held as (dynamic, slice, x, y), and the maps, the mask and the
    %   thresholds as (slice, x, y).
    %
    % --------------------------------------------------------------------------------------


    properties (Access = public)

        % App version
        appVersion = "2.0.0"        % App version

        % The dataset
        td                          % dscData, the data this session works on

        % Folders and closing
        appFolder                   % application folder 
        oldFolder                   % folder MATLAB was in when the app started
        oldWarningState             % warning state MATLAB had when the app started
        busyFlag = false            % a step is running: status BUSY until OK or ERROR
        closeRequested = false      % the window was closed while a step was running
        closeTimer                  % timer that closes the app once that step has finished
        dicomImportFolder = ''      % DICOM import folder
        dicomExportFolder = ''      % export folder chosen when no scanner folder is found
        gifExportFolder = ''        % GIF export folder chosen when no scanner folder is found

        % Flags
        elastixDetectedFlag = false % elastix can be run
        validDataFlag = false       % a valid dataset is loaded
        validRegFlag = false        % the images have been registered
        validFitFlag = false        % maps have been fitted
        abortRegFlag = false        % abort the registration
        abortFitFlag = false        % abort the fit, asked for when the app is closed

        % The image viewer
        clickedPoint = []           % [x y slice] of the last click in the image
        cross                       % cross-hair on the image
        aifRegionPlot               % the AIF search region drawn on the image
        aifVoxelPlot                % the AIF voxels drawn on the image
        maskBeforeOperation = []    % the mask before the last erode, dilate, close or open, for Undo
        maskAfterOperation = []     % the mask that operation left, so Undo can tell it is current

        % Colour maps
        cbvcmap                     % rCBV colour map
        cbfcmap                     % rCBF colour map
        mttcmap                     % MTT colour map
        ttpcmap                     % TTP colour map
        cbvlccmap                   % rCBVlc colour map
        k2cmap                      % K2 colour map

        % Colours
        brightred                   % red
        red                         % red
        brightgreen                 % green
        green                       % green
        yellow                      % yellow
        orange                      % orange
        blue                        % blue
        lightblue                   % light blue
        grey                        % grey
        guiBlack                    % app black
        guiGrey                     % app grey
        guiMiddleGrey               % app middle grey
        guiDarkGrey                 % app dark grey
        guiWhite                    % app white
        guiBlackBlue                % app black/blueish

        % Sounds
        warningSound                % warning sound
        errorSound                  % error sound

        % Settings and log
        jsonPar = struct()          % settings, from dscmapp.io.readSettingsFile
        logFile = -1                % file id of this session's log, -1 when there is none
        logFileName = ''            % full path of this session's log

    end


    methods (Access = private)


        %------------------------------------------------
        %
        %  GUI layout
        %
        %------------------------------------------------


        % Traffic lights
        function SetLights(app,code)

            % Purpose:
            %   Colours the six lamps beside the dashboard buttons.
            %
            % How it works:
            %   0 is red, 1 is green, 3 is yellow for the step that is running,
            %   anything else leaves the lamp as it is.

            lamps = {app.Lamp1, app.Lamp2, app.Lamp3, app.Lamp4, app.Lamp5, app.Lamp6};

            for k = 1:numel(lamps)
                if code(k) == 0
                    lamps{k}.Color = app.red;
                elseif code(k) == 1
                    lamps{k}.Color = app.green;
                elseif code(k) == 3
                    lamps{k}.Color = app.yellow;
                end
            end

            drawnow;

        end


        % Buttons on/off
        function SetButtons(app,code)

            % Purpose:
            %   Enables or disables the seven dashboard buttons.
            %
            % How it works:
            %   One entry per button, in dashboard order: load, register, mask, fit,
            %   DICOM export, GIF export, exit.

            app.SetEnableFcn({app.LoadDCMfileButton}, code(1) ~= 0);
            app.SetEnableFcn({app.RegisterButton, app.RegistrationDropDown}, code(2) ~= 0);
            app.SetEnableFcn({app.MaskButton}, code(3) ~= 0);
            app.SetEnableFcn({app.FitDSCButton}, code(4) ~= 0);
            app.SetEnableFcn({app.DCMNIFTIButton}, code(5) ~= 0);
            app.SetEnableFcn({app.GIFButton}, code(6) ~= 0);
            app.SetEnableFcn({app.ExitButton}, code(7) ~= 0);

            drawnow;

        end


        % Enable or disable a set of widgets
        function SetEnableFcn(app, widgets, tf) %#ok<INUSD>

            % Purpose:
            %   Sets Enable on every widget in the list to the same state.

            state = 'off';

            if tf
                state = 'on';
            end

            for k = 1:numel(widgets)
                widgets{k}.Enable = state;
            end

        end


        % Set application colors
        function SetAppColorsFcn(app, guiColors)

            % Purpose:
            %   Applies one of the two colour schemes to every panel, label and axis.
            %
            % How it works:
            %   guiColors is backgroundColor, fontColor, boxColor, gaugeColor,
            %   gaugeBackgroundColor, figureColor, movieFigureColor, gridColor, and two
            %   colours this app does not use.

            backgroundColor = guiColors{1};
            fontColor = guiColors{2};
            gaugeColor = guiColors{4};
            gaugeBackgroundColor = guiColors{5};
            figureColor = guiColors{6};
            movieFigureColor = guiColors{7};

            % Panels
            panels = {app.DashboardTab, app.RawImagesTab, app.SequenceParametersTab, app.FitParametersTab, ...
                app.FitEvaluationTab, app.AppSettingsTab, app.ArterialInputFunctionTab, app.rCBVrCBFTab, ...
                app.MTTTTPTab, app.rCBVlcK2Tab, app.ImageSelectionPanel, app.MapImageSelectionPanel1, ...
                app.MapImageSelectionPanel2, app.MapImageSelectionPanel3, app.MaskSettingsPanel, ...
                app.ImageWindowLevelPanel, app.DataSelectionPanel, app.AIFSettingsPanel, ...
                app.CBFfitMethodButtonGroup, app.ColorsButtonGroup};

            for k = 1:numel(dscmapp.Constants.MAP_WIDGETS)
                panels{end+1} = app.(dscmapp.Constants.MAP_WIDGETS{k} + "ColorMapButtonGroup"); %#ok<AGROW>
            end

            app.DSCmapUIFigure.Color = backgroundColor;

            for k = 1:numel(panels)
                panels{k}.BackgroundColor = backgroundColor;
            end

            % Texts
            texts = {app.SlicesEditFieldLabel, app.DynamicsEditFieldLabel, app.thresholdEditFieldLabel, ...
                app.WindowEditFieldLabel, app.LevelEditFieldLabel, app.FirstDynamicEditFieldLabel, ...
                app.MaxAIFpixelsEditFieldLabel, app.LongAxisEditFieldLabel, app.ShortAxisEditFieldLabel, ...
                app.QuadraticCheckBoxLabel, app.SVDButton, app.cSVDButton, app.oSVDButton, app.SVDLabel, ...
                app.cSVDLabel, app.oSVDLabel, app.KhEditFieldLabel, app.RhoEditFieldLabel, ...
                app.AutoSoundsCheckBoxLabel, app.BlackWhiteButton, app.ClassicButton, ...
                app.DicomFolderViewFieldLabel, app.TRViewFieldLabel, app.TEViewFieldLabel, ...
                app.FlipAngleViewFieldLabel, app.NRDynamicsViewFieldLabel, app.AspectRatioViewFieldLabel, ...
                app.FOVViewFieldLabel, app.dimxViewFieldLabel, app.dimyViewFieldLabel, app.SlicesViewFieldLabel, ...
                app.SliceThicknessViewFieldLabel, app.XViewFieldLabel, app.YViewFieldLabel, ...
                app.MapSlicesEditFieldLabel1, app.MapSlicesEditFieldLabel2, app.MapSlicesEditFieldLabel3, ...
                app.ImageLabel, app.rCBVLabel, app.rCBF1sLabel, app.MTTsLabel, app.TTPsLabel, ...
                app.rCBVlcLabel, app.K2Label};

            for k = 1:numel(dscmapp.Constants.MAP_WIDGETS)
                prefix = dscmapp.Constants.MAP_WIDGETS{k};
                texts = [texts, {app.(prefix + "ScaleMaxLabel"), app.(prefix + "ScaleMinLabel")}, ...
                    app.ColorMapButtonsFcn(k)]; %#ok<AGROW>
            end

            for k = 1:numel(texts)
                texts{k}.FontColor = fontColor;
            end

            % Gauges
            app.RegProgressGauge.FontColor = gaugeColor;
            app.RegProgressGauge.BackgroundColor = gaugeBackgroundColor;
            app.FitProgressGauge.FontColor = gaugeColor;
            app.FitProgressGauge.BackgroundColor = gaugeBackgroundColor;

            % Images and figures
            axesList = [{app.RawDataFig}, app.MapAxesFcn];

            for k = 1:numel(axesList)
                axesList{k}.Color = movieFigureColor;
                axesList{k}.XColor = movieFigureColor;
                axesList{k}.YColor = movieFigureColor;
            end

            plots = {app.ArterialInputFunctionPlot, app.ConcInputFunctionPlot, app.ResidualPlot, app.ConcentrationPlot};

            for k = 1:numel(plots)
                plots{k}.Color = figureColor;
                plots{k}.XColor = fontColor;
                plots{k}.YColor = fontColor;
            end

        end % SetAppColorsFcn


        % Check for a newer version
        function CheckVersionFnc(app)

            % Purpose:
            %   Tells the user when a newer release is published.
            %
            % How it works:
            %   Each release of the app lives in a repository of its own,
            %   DSCmapping-v<major.minor>, whose appversion.txt holds
            %   DSCmapp_version=x.y.z. The raw file is read, newest repository first,
            %   and the first that answers is compared with this version. Version 1.2.3
            %   read the github page of the file instead, which is HTML and not the file.
            %
            %   A repository that does not exist answers at once with an HTTP status, and
            %   the next one is worth trying. Any other failure -- no network, a timeout,
            %   a proxy refusing the connection -- would fail the same way for every
            %   repository, each after the full timeout, so the search stops there, as
            %   in the other MRI apps.
            %
            %   Any failure is reported once and is not an error. Whether to look, and how
            %   long to wait, are settings.

            if ~app.jsonPar.checkForUpdates
                app.TextMessage('Version check switched off in the settings ...');
                return
            end

            repositories = ["DSCmapping-v2.3", "DSCmapping-v2.2", "DSCmapping-v2.1", "DSCmapping-v2.0", "DSCmapping-v1.2"];
            opts = weboptions('Timeout', app.jsonPar.versionCheckTimeout, 'ContentType', 'text', 'CharacterEncoding', 'UTF-8');
            tok = {};

            for repository = repositories
                try
                    webTxt = strtrim(string(webread("https://raw.githubusercontent.com/Moby1971/" + repository + "/main/appversion.txt", opts)));
                    tok = regexp(webTxt, 'DSCmapp_version\s*=\s*([0-9]+(?:\.[0-9]+){1,3})', 'tokens', 'once', 'ignorecase');
                catch webME
                    tok = {};
                    if ~contains(webME.identifier, 'StatusCodeError')
                        break
                    end
                end
                if ~isempty(tok)
                    break
                end
            end

            if isempty(tok)
                app.TextMessage("Could not check the DSC mapping app version ...");
                return
            end

            newVersion = sscanf(char(tok{1}), '%d.%d.%d').';
            current = sscanf(char(app.appVersion), '%d.%d.%d').';
            newVersion(end+1:3) = 0;
            current(end+1:3) = 0;

            app.TextMessage("Current version = " + app.appVersion);
            app.TextMessage("Newest version = " + string(tok{1}));

            difference = find(newVersion ~= current, 1);

            if ~isempty(difference) && newVersion(difference) > current(difference)
                app.TextMessage("A new version of the DSC mapping app is available ...");
                app.TextMessage("Visit https://github.com/Moby1971/ to update ...");
            else
                app.TextMessage("The DSC mapping app is up to date ...");
            end

        end % CheckVersionFnc


        % Switch fit parameters buttons on/off
        function SetFitButtons(app,code)

            % Purpose:
            %   Enables or disables the fit parameters: the first dynamic, the AIF
            %   search settings, the deconvolution method, kh and rho.

            widgets = {app.FirstDynamicEditField, app.FirstDynamicEditFieldLabel, app.MaxAIFpixelsEditField, ...
                app.MaxAIFpixelsEditFieldLabel, app.LongAxisEditField, app.LongAxisEditFieldLabel, ...
                app.ShortAxisEditField, app.ShortAxisEditFieldLabel, app.QuadraticCheckBox, ...
                app.QuadraticCheckBoxLabel, app.SVDButton, app.cSVDButton, app.oSVDButton, app.SVDLabel, ...
                app.cSVDLabel, app.oSVDLabel, app.KhEditField, app.KhEditFieldLabel, app.RhoEditField, ...
                app.RhoEditFieldLabel};
            app.SetEnableFcn(widgets, code ~= 0);

            drawnow;

        end


        % Switch image selection buttons on/off
        function SetImageSelectionButtons(app,code)

            % Purpose:
            %   Enables or disables the image viewer's slice, dynamic, window and level
            %   controls.

            widgets = {app.SlicesEditField, app.SlicesEditFieldLabel, app.SlicesSlider, app.DynamicsEditField, ...
                app.DynamicsEditFieldLabel, app.DynamicsSlider, app.WindowEditField, app.WindowEditFieldLabel, ...
                app.WindowSlider, app.LevelEditField, app.LevelEditFieldLabel, app.LevelSlider, app.AutoLWButton};
            app.SetEnableFcn(widgets, code ~= 0);

            drawnow;

        end


        % Switch image segmentation buttons on/off
        function SetSegmentationButtons(app,code)

            % Purpose:
            %   Enables or disables the threshold, the mask operations and their size, and
            %   Undo when there is an operation to undo.

            widgets = {app.thresholdEditField, app.thresholdEditFieldLabel, app.ThresholdSlider, ...
                app.ErodeButton, app.DilateButton, app.CloseButton, app.OpenButton, app.MaskSizeEditField};
            app.SetEnableFcn(widgets, code ~= 0);
            app.SetEnableFcn({app.UndoButton}, code ~= 0 && ~isempty(app.maskAfterOperation));

            drawnow;

        end


        % Map controls on/off
        function SetMapButtons(app,code)

            % Purpose:
            %   Enables or disables the map viewers: the slice controls, the scales and
            %   the colour maps of the six maps.
            %
            % How it works:
            %   Maps can only be looked at once they have been fitted, so every step
            %   switches these off while it runs and on again when valid maps remain.
            %   The slice controls are only offered when there is more than one slice.

            on = code ~= 0;

            app.SetEnableFcn({app.MapSlicesEditField1, app.MapSlicesEditFieldLabel1, app.MapSlicesSlider1, ...
                app.MapSlicesEditField2, app.MapSlicesEditFieldLabel2, app.MapSlicesSlider2, ...
                app.MapSlicesEditField3, app.MapSlicesEditFieldLabel3, app.MapSlicesSlider3}, on && app.td.ns > 1);

            widgets = {};

            for k = 1:numel(dscmapp.Constants.MAP_WIDGETS)
                prefix = dscmapp.Constants.MAP_WIDGETS{k};
                widgets = [widgets, {app.(prefix + "ScaleEditField"), app.(prefix + "ScaleMaxLabel"), ...
                    app.(prefix + "ScaleMinEditField"), app.(prefix + "ScaleMinLabel"), ...
                    app.(prefix + "ScaleAutoButton")}, app.ColorMapButtonsFcn(k)]; %#ok<AGROW>
            end

            app.SetEnableFcn(widgets, on);

            drawnow;

        end


        % Initialize the GUI
        function InitializeUI(app)

            % Purpose:
            %   Puts the interface in its starting state: colours, icons, sounds,
            %   plot settings, colour maps and the parameter fields.
            %
            % How it works:
            %   The registration and fit fields start from the settings, whose own
            %   defaults are the shipped parameter defaults in +dscmapp, so the values
            %   a script gets and the values the app starts with are the same numbers.

            app.TextMessage('Initializing UI ...');

            % App version
            app.Credits.Text = strcat("Gustav Strijkers, Amsterdam UMC, Version ",app.appVersion);

            % Define app colors
            app.brightred = 'Red';
            app.red = '#D95319';
            app.brightgreen = 'Green';
            app.green = '#6CB557';
            app.yellow = 'Yellow';
            app.orange = '#FDA400';
            app.blue = '#0073BE';
            app.lightblue = '#DEE6EB';
            app.grey = '#787878';
            app.guiBlack = [0.15,0.15,0.15];
            app.guiGrey = [0.94,0.94,0.94];
            app.guiMiddleGrey = [0.9,0.9,0.9];
            app.guiDarkGrey = [0.5,0.5,0.5];
            app.guiWhite = [1.0,1.0,1.0];
            app.guiBlackBlue = [0.02,0.13,0.3];

            % Logos
            app.TulpLogo.ImageSource = 'AmsterdamUMC.png';
            app.MRSImage.ImageSource = 'MRSolutionsLogo.gif';

            % Sounds
            [app.warningSound,~] = audioread('Glass.aiff');
            [app.errorSound,~] = audioread('Ping.aiff');

            % Icons
            app.LoadDCMfileButton.Icon = 'disk.png';
            app.RegisterButton.Icon = 'sort.gif';
            app.MaskButton.Icon = 'threshold.gif';
            app.FitDSCButton.Icon = 'exponential.gif';
            app.DCMNIFTIButton.Icon = 'disk.png';
            app.GIFButton.Icon = 'disk.png';
            app.ExitButton.Icon = 'exit.png';
            app.StatusImage.ImageSource = 'statusokay.gif';
            app.RawImage.ImageSource = 'square.gif';

            for k = 1:numel(dscmapp.Constants.MAP_WIDGETS)
                app.(dscmapp.Constants.MAP_WIDGETS{k} + "Image").ImageSource = 'square.gif';
            end

            % Lights and buttons
            app.SetLights([0,0,0,0,0,0]);
            app.SetButtons([0,0,0,0,0,0,1]);
            app.SetFitButtons(0);
            app.SetImageSelectionButtons(0);
            app.SetSegmentationButtons(0);
            app.SetMapButtons(0);
            app.AbortRegButton.Enable = 'off';

            % Plot properties
            plots = [{app.RawDataFig, app.ArterialInputFunctionPlot, app.ConcInputFunctionPlot, app.ResidualPlot, ...
                app.ConcentrationPlot}, app.MapAxesFcn];

            for k = 1:numel(plots)
                plots{k}.Toolbar.Visible = 'off';
                plots{k}.Interactions = [];
            end

            % Colour maps, sounds and colour scheme, from the settings
            colourMaps = {app.jsonPar.display.cbvColorMap, app.jsonPar.display.cbfColorMap, ...
                app.jsonPar.display.mttColorMap, app.jsonPar.display.ttpColorMap, ...
                app.jsonPar.display.cbvlcColorMap, app.jsonPar.display.k2ColorMap};

            for k = 1:numel(colourMaps)
                buttons = app.ColorMapButtonsFcn(k);
                buttons{strcmp(colourMaps{k}, {'gray', 'lipari', 'winter', 'parula', 'navia'})}.Value = 1;
                app.SetColorMapFcn(k);
            end

            app.AutoSoundsCheckBox.Value = logical(app.jsonPar.sounds);

            if strcmp(app.jsonPar.colorScheme, 'classic')
                app.ClassicButton.Value = true;
            else
                app.BlackWhiteButton.Value = true;
            end

            % Parameter fields from the settings
            app.FirstDynamicEditField.Value = app.jsonPar.fitting.firstDynamic;
            app.LongAxisEditField.Value = app.jsonPar.fitting.aifLongAxis;
            app.ShortAxisEditField.Value = app.jsonPar.fitting.aifShortAxis;
            app.MaxAIFpixelsEditField.Value = app.jsonPar.fitting.aifMaxVoxels;
            app.QuadraticCheckBox.Value = logical(app.jsonPar.fitting.quadratic);
            app.(app.jsonPar.fitting.method + "Button").Value = true;
            app.KhEditField.Value = app.jsonPar.fitting.kh;
            app.RhoEditField.Value = app.jsonPar.fitting.rho;
            app.RegistrationDropDown.Value = app.jsonPar.registration.method;
            app.ResetScalesFcn;

            app.SetStatus(3);

            drawnow;

        end


        %------------------------------------------------
        %
        %  Figures and Plots
        %
        %------------------------------------------------


        % Displays the mask in the raw image data
        function PlotImageFig(app)

            % Purpose:
            %   Draws the selected dynamic and slice with the mask overlaid in red, and
            %   the AIF search on top: the cross-hair where the image was clicked, the
            %   search region in green and the AIF voxels in blue.
            %
            % How it works:
            %   Since one axes cannot hold two colour maps, the map is split in a grey
            %   half [0 1] for the image and a red half [1 2] for the mask overlay. The
            %   threshold fields are then set for the slice shown, on the first image
            %   of the analysis, which the mask is made from.

            if ~app.validDataFlag
                return
            end

            cla(app.RawDataFig);
            hold(app.RawDataFig,'on');

            cmap1 = [linspace(0,1,128)',linspace(0,1,128)',linspace(0,1,128)'];
            cmap2 = [linspace(0,1,128)', zeros(128,2)];
            cmap = [cmap1;cmap2];
            cmap(cmap > 1) = 1;

            slice = app.SlicesEditField.Value;
            dynamic = app.DynamicsEditField.Value;
            im = reshape(app.td.images(dynamic,slice,:,:), app.td.dimx, app.td.dimy);

            % Scale
            if app.AutoLWButton.Value == 1
                maxIm = max(im(:));
                if ~(maxIm > 0)
                    maxIm = 1;
                end
                app.WindowEditField.Value = maxIm;
                app.WindowSlider.Value = maxIm;
                app.LevelEditField.Value = maxIm/2;
                app.LevelSlider.Value = maxIm/2;
            end

            % Window and level
            im = (1/app.WindowEditField.Value)*(im - app.LevelEditField.Value + app.WindowEditField.Value/2);
            im(im<0) = 0;
            im(im>0.9999) = 0.9999;

            xlim(app.RawDataFig,[1, app.td.dimx]);
            ylim(app.RawDataFig,[1, app.td.dimy]);
            imshow(rot90(im),[0,2],'Colormap',cmap,'InitialMagnification','fit','Parent',app.RawDataFig);

            % Overlay with the mask
            mask = reshape(app.td.mask(slice,:,:), app.td.dimx, app.td.dimy);
            overlay = imshow(rot90(2 - mask),[0 2],'Colormap',cmap,'InitialMagnification','fit','Parent',app.RawDataFig);
            set(overlay,'AlphaData',rot90(0.7 - 0.7*mask));

            % Image aspect ratio
            daspect(app.RawDataFig,[1 app.td.pixelAspectRatio 1]);

            % The AIF search on this slice
            app.PlotAIFOverlayFcn(slice);

            hold(app.RawDataFig,'off');
            drawnow;

            % Threshold limits and the value for this slice
            reference = app.td.images(1,slice,:,:);
            top = max(reference(:)) + 1;
            app.thresholdEditField.Limits = [0 top];
            app.ThresholdSlider.Limits = [0 top];
            thr = min(max(app.td.threshold(slice),0),top);
            app.thresholdEditField.Value = thr;
            app.ThresholdSlider.Value = thr;

        end % PlotImageFig


        % The AIF search drawn on the image
        function PlotAIFOverlayFcn(app, slice)

            % Purpose:
            %   Draws what belongs to the AIF on the slice shown: the search region in
            %   green, the AIF voxels as blue squares, and the cross-hair where the
            %   image was clicked.
            %
            % How it works:
            %   The squares are sized to one pixel of the image as it is drawn, as in
            %   version 1.2.3. The axes are held by the caller.

            dimy = app.td.dimy;
            delete(app.aifRegionPlot);
            delete(app.aifVoxelPlot);
            delete(app.cross);

            if ~isempty(app.td.aifPoint) && app.td.aifPoint(3) == slice

                originalUnits = app.RawDataFig.Units;
                app.RawDataFig.Units = 'points';
                axSize = app.RawDataFig.Position(3:4);
                app.RawDataFig.Units = originalUnits;
                pixPerUnit = axSize ./ [diff(xlim(app.RawDataFig)), diff(ylim(app.RawDataFig))];
                markerArea = ceil(min(pixPerUnit))^2;

                [xcor, ycor] = find(app.td.aifRegion);
                app.aifRegionPlot = scatter(app.RawDataFig, xcor, dimy-ycor+1, markerArea, 's', 'MarkerFaceColor', 'g', ...
                    'MarkerEdgeColor', 'g', 'MarkerEdgeAlpha', 0.1, 'MarkerFaceAlpha', 0.3);
                app.aifVoxelPlot = scatter(app.RawDataFig, app.td.aifVoxels(:,1), dimy-app.td.aifVoxels(:,2)+1, 's', ...
                    'MarkerFaceColor', app.blue, 'MarkerEdgeColor', app.blue);

            end

            if ~isempty(app.clickedPoint) && app.clickedPoint(3) == slice
                app.cross = drawcrosshair(app.RawDataFig, 'Position', [app.clickedPoint(1) dimy-app.clickedPoint(2)+1], ...
                    'Color', 'g', 'LineWidth', 1);
            end

        end % PlotAIFOverlayFcn


        % Plots the maps for the current slice
        function PlotMaps(app)

            % Purpose:
            %   Draws the six maps of the selected slice, masked.
            %
            % How it works:
            %   Each map is shown from the min to the max field of its scale, with its
            %   colour map and a colour bar. A redraw replaces the images already on the
            %   axes.

            if ~app.validFitFlag
                return
            end

            slice = app.MapSlicesEditField1.Value;
            dimx = app.td.dimx;
            dimy = app.td.dimy;
            axesList = app.MapAxesFcn;
            mask = reshape(app.td.mask(slice,:,:), dimx, dimy);

            for k = 1:numel(axesList)

                ax = axesList{k};
                hold(ax,'on');

                % With hold on, imshow would otherwise stack a new copy at every redraw
                delete(findobj(ax,'Type','image'));

                prefix = dscmapp.Constants.MAP_WIDGETS{k};
                high = max(app.(prefix + "ScaleEditField").Value, dscmapp.Constants.MIN_SCALE);
                low = app.(prefix + "ScaleMinEditField").Value;

                if low >= high
                    low = 0;
                end

                im = reshape(app.td.(dscmapp.Constants.MAP_FIELDS{k})(slice,:,:), dimx, dimy).*mask;
                xlim(ax,[1, dimx]);
                ylim(ax,[1, dimy]);
                imshow(rot90(im),[low high],'Colormap',app.MapColorMapFcn(k),'InitialMagnification','fit','Parent',ax);
                daspect(ax,[1 app.td.pixelAspectRatio 1]);
                colorbar(ax,'east','Ticks',[]);
                hold(ax,'off');

            end

            drawnow;

        end % PlotMaps


        % The signal under the cross-hair
        function PlotSignalFcn(app)

            % Purpose:
            %   Draws the signal of the clicked voxel in the AIF graph, and clears the
            %   concentration graph, while the AIF around it is searched for.

            if ~app.validDataFlag || isempty(app.clickedPoint)
                return
            end

            p = app.clickedPoint;
            signal = app.td.images(:,p(3),p(1),p(2))/dscmapp.Constants.DISPLAY_MAX;
            time = app.td.time/1000;

            cla(app.ConcInputFunctionPlot);
            cla(app.ArterialInputFunctionPlot);
            plot(app.ArterialInputFunctionPlot,time,signal);
            xlim(app.ArterialInputFunctionPlot,[-0.5, 1.1*max(time(:))]);
            ylim(app.ArterialInputFunctionPlot,[0, 1.1*max([signal(:); eps])]);

        end % PlotSignalFcn


        % Plots the AIF
        function PlotAIFFcn(app)

            % Purpose:
            %   Draws the arterial input function: the mean signal of its voxels, and
            %   its concentration.

            if ~app.validDataFlag || isempty(app.td.aif)
                return
            end

            voxels = app.td.aifVoxels;
            series = zeros(app.td.nr, size(voxels,1));

            for i = 1:size(voxels,1)
                series(:,i) = app.td.images(:,voxels(i,3),voxels(i,1),voxels(i,2));
            end

            signal = mean(series,2)/dscmapp.Constants.DISPLAY_MAX;
            time = app.td.time/1000;

            cla(app.ArterialInputFunctionPlot);
            plot(app.ArterialInputFunctionPlot,time,signal);
            xlim(app.ArterialInputFunctionPlot,[-0.5, 1.1*max(time(:))]);
            ylim(app.ArterialInputFunctionPlot,[0, 1.1*max([signal(:); eps])]);

            cla(app.ConcInputFunctionPlot);
            plot(app.ConcInputFunctionPlot,time,app.td.aif);
            xlim(app.ConcInputFunctionPlot,[-0.5, 1.1*max(time(:))]);
            ylim(app.ConcInputFunctionPlot,[-1, 1.1*max([app.td.aif(:); eps])]);

        end % PlotAIFFcn


        % Plots the fitted data and difference
        function PlotFitFcn(app)

            % Purpose:
            %   Draws, for the clicked voxel, the concentration with the fit the
            %   deconvolution gives, and the difference between the two.
            %
            % How it works:
            %   The fit is the residue function convolved with the AIF the maps were
            %   computed with, times TR, as in version 1.2.3. It is drawn for the slice
            %   that was clicked.

            if ~app.validFitFlag || isempty(app.clickedPoint) || isempty(app.td.mapAif)
                return
            end

            p = app.clickedPoint;
            time = app.td.time/1000;
            signal = app.td.conc(:,p(3),p(1),p(2))';
            fit = filter(app.td.mapAif.fit,1,app.td.residual(:,p(3),p(1),p(2))')*app.td.tr/1000;
            top = max([signal(:); fit(:)]);

            cla(app.ConcentrationPlot);
            hold(app.ConcentrationPlot,'on');
            plot(app.ConcentrationPlot,time,signal);
            plot(app.ConcentrationPlot,time,fit,'LineWidth',2);
            xlim(app.ConcentrationPlot,[-0.5, 1.1*max(time(:))]);
            ylim(app.ConcentrationPlot,[-5, 1.1*top + eps]);
            hold(app.ConcentrationPlot,'off');

            cla(app.ResidualPlot);
            plot(app.ResidualPlot,time,signal - fit);
            xlim(app.ResidualPlot,[-0.5, 1.1*max(time(:))]);
            ylim(app.ResidualPlot,[-0.5*(2.5+top), 0.5*(2.5+top)]);

        end % PlotFitFcn


        %------------------------------------------------
        %
        %  Parameters and settings
        %
        %------------------------------------------------


        % Copy the acquisition outputs to the fields that display them
        function MirrorDataOutputsFcn(app)

            % Purpose:
            %   Puts every acquisition value the app shows into its display field,
            %   in one place.
            %
            % How it works:
            %   These fields are read-only mirrors of the dataset: nothing below the
            %   app writes them, and nothing reads them back for a decision.
            %   tests/tMirrors checks that the import sets everything shown here. The
            %   number of dynamics is the number the analysis uses, as in 1.2.3, and a
            %   value is kept within the limits of its field.

            app.DicomFolderViewField.Value = char(app.td.dicomFolder);
            app.SetMirrorFcn(app.TRViewField, app.td.tr);
            app.SetMirrorFcn(app.TEViewField, app.td.te);
            app.SetMirrorFcn(app.FlipAngleViewField, app.td.fa);
            app.SetMirrorFcn(app.NRDynamicsViewField, app.td.nr);
            app.SetMirrorFcn(app.dimxViewField, app.td.dimx);
            app.SetMirrorFcn(app.dimyViewField, app.td.dimy);
            app.SetMirrorFcn(app.SlicesViewField, app.td.ns);
            app.SetMirrorFcn(app.SliceThicknessViewField, app.td.sl);
            app.SetMirrorFcn(app.FOVViewField, app.td.fov);
            app.SetMirrorFcn(app.AspectRatioViewField, app.td.aspectRatio);

        end % MirrorDataOutputsFcn


        % One numeric mirror
        function SetMirrorFcn(app, field, value) %#ok<INUSL>

            % Purpose:
            %   Shows a value in a numeric field, within the field's limits.

            field.Value = min(max(double(value), field.Limits(1)), field.Limits(2));

        end % SetMirrorFcn


        % Fit the slice and dynamic widgets to the dataset
        function SetWidgetLimitsFcn(app)

            % Purpose:
            %   Sets the ranges of every slice and dynamic control to the size of the
            %   series.

            sliceWidgets = {app.SlicesEditField, app.SlicesSlider, app.MapSlicesEditField1, app.MapSlicesSlider1, ...
                app.MapSlicesEditField2, app.MapSlicesSlider2, app.MapSlicesEditField3, app.MapSlicesSlider3};

            app.SetRangeFcn(sliceWidgets, app.td.ns);
            app.SetRangeFcn({app.DynamicsEditField, app.DynamicsSlider}, app.td.nr);

        end % SetWidgetLimitsFcn


        % The range of a set of widgets
        function SetRangeFcn(app, widgets, n) %#ok<INUSL>

            % Purpose:
            %   Sets a set of edit fields and sliders to run from 1 to n.
            %
            % How it works:
            %   The value is put at 1 first, so that it is never outside the new range.
            %   A slider needs two different ends, so a range of one runs to 2 there.

            for k = 1:numel(widgets)
                widgets{k}.Value = 1;
                if isa(widgets{k}, 'matlab.ui.control.Slider')
                    widgets{k}.Limits = [1, max(n, 2)];
                else
                    widgets{k}.Limits = [1, max(n, 1)];
                end
            end

        end % SetRangeFcn


        % Clear what belongs to the previous images
        function ResetForNewImagesFcn(app)

            % Purpose:
            %   Clears the plots, the AIF search and the scales, so that nothing from
            %   the last images survives into these, after a load or a change of the
            %   first dynamic.
            %
            % How it works:
            %   The maps, the mask, the thresholds and the AIF were already cleared by
            %   the dataset.

            % Window and level
            app.WindowEditField.Value = dscmapp.Constants.DISPLAY_MAX;
            app.LevelEditField.Value = dscmapp.Constants.DISPLAY_LEVEL;
            app.WindowSlider.Value = dscmapp.Constants.DISPLAY_MAX;
            app.LevelSlider.Value = dscmapp.Constants.DISPLAY_LEVEL;
            app.AutoLWButton.Value = 1;

            % Segmentation: nothing of the last mask to undo
            app.maskBeforeOperation = [];
            app.maskAfterOperation = [];

            % The AIF search and the plots
            app.clickedPoint = [];
            app.XViewField.Value = 0;
            app.YViewField.Value = 0;
            delete(app.cross);
            delete(app.aifRegionPlot);
            delete(app.aifVoxelPlot);
            app.ClearMapsFcn;
            cla(app.RawDataFig);
            cla(app.ArterialInputFunctionPlot);
            cla(app.ConcInputFunctionPlot);
            cla(app.ConcentrationPlot);
            cla(app.ResidualPlot);
            app.ResetScalesFcn;

        end % ResetForNewImagesFcn


        % Empty map axes
        function ClearMapsFcn(app)

            % Purpose:
            %   Clears the six map axes.

            axesList = app.MapAxesFcn;

            for k = 1:numel(axesList)
                cla(axesList{k});
            end

        end % ClearMapsFcn


        % The starting scales
        function ResetScalesFcn(app)

            % Purpose:
            %   Puts every map scale at its default top and a bottom of 0.

            for k = 1:numel(dscmapp.Constants.MAP_WIDGETS)
                prefix = dscmapp.Constants.MAP_WIDGETS{k};
                app.(prefix + "ScaleMinEditField").Value = 0;
                app.(prefix + "ScaleEditField").Value = dscmapp.Constants.DEFAULT_SCALE;
            end

        end % ResetScalesFcn


        % Change the map slice
        function ChangeMapSlices(app, value)

            % Purpose:
            %   Moves the three map viewers to the same slice.

            value = min(max(1, round(value)), max(1, app.td.ns));

            for k = 1:3
                app.("MapSlicesEditField" + k).Value = value;
                app.("MapSlicesSlider" + k).Value = value;
            end

        end % ChangeMapSlices


        % A scale that shows a map well
        function AutoScaleFcn(app, k)

            % Purpose:
            %   Sets the scale of map k to what dscmapp.util.autoScale gives for the
            %   slice shown, and its bottom to 0.
            %
            % How it works:
            %   MTT and TTP are rounded to whole numbers and the others to two
            %   decimals, as in version 1.2.3.

            decimals = [2 2 0 0 2 2];
            prefix = dscmapp.Constants.MAP_WIDGETS{k};
            scale = dscmapp.util.autoScale(app.td.(dscmapp.Constants.MAP_FIELDS{k}), app.MapSlicesEditField1.Value, decimals(k));

            app.(prefix + "ScaleMinEditField").Value = 0;
            app.(prefix + "ScaleEditField").Value = scale;

        end % AutoScaleFcn


        % The axes of the six maps
        function axesList = MapAxesFcn(app)

            % Purpose:
            %   The map axes, in the order of dscmapp.Constants.MAP_FIELDS.

            axesList = {app.CBVMapFig, app.CBFMapFig, app.MTTMapFig, app.TTPMapFig, app.CBVlcMapFig, app.K2MapFig};

        end % MapAxesFcn


        % The colour map buttons of one map
        function buttons = ColorMapButtonsFcn(app, k)

            % Purpose:
            %   The five colour map radio buttons of map k -- gray, lipari, winter,
            %   parula and navia, in that order -- whose names are not all alike.

            switch k
                case 1
                    buttons = {app.CBVgrayButton, app.CBVlipariButton, app.CBVwinterButton, app.CBVparulaButton, app.CBVnaviaButton};
                case 2
                    buttons = {app.CBFgrayButton, app.CBFlipariButton, app.CBFwinterButton, app.CBFparulaButton, app.CBFnaviaButton};
                case 3
                    buttons = {app.MTTgrayButton, app.MTTlipariButton, app.MTTwinterButton, app.MTTparulaButton, app.MTTnaviaButton};
                case 4
                    buttons = {app.TTPgrayButton, app.TTPlipariButton, app.TTPwinterButton, app.TTPparulaButton, app.TTPnaviaButton};
                case 5
                    buttons = {app.CBVlcgrayButton, app.CBVllipariButton, app.CBVlcwinterButton, app.CBVlcparulaButton, app.CBVlcNaviaButton};
                otherwise
                    buttons = {app.K2grayButton, app.K2lipariButton, app.K2winterButton, app.K2parulaButton, app.K2naviaButton};
            end

        end % ColorMapButtonsFcn


        % Set the colour map of one map from its buttons
        function SetColorMapFcn(app, k)

            % Purpose:
            %   Reads the five colour map buttons of map k and stores the colour map
            %   they name.
            %
            % How it works:
            %   The first entry is forced to black so the background of a map stays
            %   black whatever the colour map does at its bottom end.

            buttons = app.ColorMapButtonsFcn(k);
            maps = {@gray, @dscmapp.util.lipariColorMap, @winter, @parula, @dscmapp.util.naviaColorMap};
            cmap = gray;

            for b = 1:numel(buttons)
                if buttons{b}.Value
                    cmap = maps{b}();
                end
            end

            cmap(1,:) = [0 0 0];
            properties = {'cbvcmap', 'cbfcmap', 'mttcmap', 'ttpcmap', 'cbvlccmap', 'k2cmap'};
            app.(properties{k}) = cmap;

        end % SetColorMapFcn


        % The colour map of one map
        function cmap = MapColorMapFcn(app, k)

            % Purpose:
            %   The stored colour map of map k.

            properties = {'cbvcmap', 'cbfcmap', 'mttcmap', 'ttpcmap', 'cbvlccmap', 'k2cmap'};
            cmap = app.(properties{k});

        end % MapColorMapFcn


        % Which map a scale field belongs to
        function k = MapOfScaleFieldFcn(app, field, suffix)

            % Purpose:
            %   Finds the map whose scale field, the max field or the min field by the
            %   suffix, is the one given, for the two callbacks the six maps share.

            k = 0;

            for m = 1:numel(dscmapp.Constants.MAP_WIDGETS)
                if field == app.(dscmapp.Constants.MAP_WIDGETS{m} + suffix)
                    k = m;
                end
            end

        end % MapOfScaleFieldFcn


        % Change the mask by a morphological operation
        function MaskOperationFcn(app, operation)

            % Purpose:
            %   Erodes, dilates, closes or opens the masked region, the one drawn in red,
            %   in every slice.
            %
            % How it works:
            %   The mask is 1 where a map is shown and the red overlay shows where it
            %   is 0, so the operation is applied to the background, 1 - mask, as in
            %   version 1.2.3: Erode shrinks the red region, Dilate grows it, Close fills
            %   gaps in it and Open removes small red specks. The element comes from
            %   dscmapp.util.maskElement: round in millimetres, with the size field as its
            %   radius in the finest pixels of dscmapp.util.pixelSize. Square pixels give
            %   the exact disk used before, size 1 being the 3 x 3 cross.
            %
            %   The mask before and after is kept for Undo, as logical arrays. A failure
            %   halfway puts the mask back as it was.

            if ~app.validDataFlag
                return
            end

            previousMask = app.td.mask;

            try

                pixelSizes = dscmapp.util.pixelSize(app.td);
                element = dscmapp.util.maskElement(app.MaskSizeEditField.Value, pixelSizes, false);

                for slice = 1:app.td.ns
                    background = 1 - reshape(app.td.mask(slice,:,:), app.td.dimx, app.td.dimy);
                    background = app.MorphologyFcn(background, operation, element);
                    app.td.mask(slice,:,:) = reshape(1 - background, 1, app.td.dimx, app.td.dimy);
                end

                app.maskBeforeOperation = previousMask == 1;
                app.maskAfterOperation = app.td.mask == 1;
                app.UndoButton.Enable = 'on';
                app.PlotImageFig;
                app.PlotMaps;

            catch ME

                app.td.mask = previousMask;
                app.TextMessage(ME.message);
                app.TextMessage('ERROR: the mask could not be changed ...');
                app.SetStatus(2);

            end

        end % MaskOperationFcn


        % One morphological operation on a background image
        function background = MorphologyFcn(app, background, operation, element) %#ok<INUSL>

            % Purpose:
            %   Erodes, dilates, closes or opens the background with the element.

            switch operation
                case "erode"
                    background = imerode(background, element);
                case "dilate"
                    background = imdilate(background, element);
                case "close"
                    background = imclose(background, element);
                case "open"
                    background = imopen(background, element);
            end

        end % MorphologyFcn


        % Whether files can be written in a folder
        function writable = FolderWritableFcn(app, folder) %#ok<INUSL>

            % Purpose:
            %   Tells whether an export can go into a folder, before the export starts.
            %
            % How it works:
            %   A folder is made there and removed again, which is the question that
            %   matters; permissions, quotas and read-only mounts all answer it.

            writable = false;

            if ~isfolder(folder)
                return
            end

            probe = fullfile(folder, ['dscmapp-write-test-' char(java.util.UUID.randomUUID)]);
            [made, ~] = mkdir(probe);

            if made
                rmdir(probe);
                writable = true;
            end

        end % FolderWritableFcn


        % Write the animations, and photograph the window beside them
        function ExportGifsFcn(app, baseFolder)

            % Purpose:
            %   Makes the numbered output folder for this export, puts two pictures of
            %   the app's window in it, and writes the animations.
            %
            % How it works:
            %   The photographs are why this lives in the app: switching tabs and
            %   calling exportapp is GUI work. They are those of version 1.2.3: the
            %   image with the AIF and the rCBV and rCBF maps, and the sequence
            %   parameters with the MTT and TTP maps. exportGifDSC is given the finished
            %   folder.

            gifFolder = dscmapp.io.numberedFolder(fullfile(baseFolder, 'GIF'), [char(app.td.tag) 'DSC']);
            app.TextMessage(strcat("GIF export folder = ", gifFolder, " ..."));

            app.TabGroup2.SelectedTab = app.RawImagesTab;
            app.TabGroup3.SelectedTab = app.rCBVrCBFTab;
            exportapp(app.DSCmapUIFigure, fullfile(gifFolder, strcat("AIFselection", app.td.tag, ".png")));
            app.TabGroup2.SelectedTab = app.SequenceParametersTab;
            app.TabGroup3.SelectedTab = app.MTTTTPTab;
            exportapp(app.DSCmapUIFigure, fullfile(gifFolder, strcat("Parameters", app.td.tag, ".png")));
            app.TabGroup2.SelectedTab = app.RawImagesTab;
            app.TabGroup3.SelectedTab = app.rCBVrCBFTab;

            dscmapp.io.exportGifDSC(app.td, gatherDisplayParams(app), gifFolder);

        end % ExportGifsFcn


        % Write a parameter set to the log
        function LogParamsFcn(app, label, params)

            % Purpose:
            %   Records exactly which parameters a registration or a fit ran with, so
            %   that a result can be traced back to what produced it.
            %
            % How it works:
            %   One line of JSON, to the log only; the message pane is not the place
            %   for it.

            try
                if app.logFile > 2
                    fprintf(app.logFile, '%s  %s parameters: %s\n', char(datetime('now','Format','HH:mm:ss')), ...
                        label, jsonencode(params));
                end
            catch
            end

        end % LogParamsFcn


        % The voxel under a click in the image
        function [xcor, ycor] = ClickedVoxelFcn(app)

            % Purpose:
            %   Turns the position of the mouse, at a click in the image, into the
            %   voxel under it.
            %
            % How it works:
            %   The image window's bottom-left corner sits at (310, 408) in the app and
            %   is 320 pixels square, the values of version 1.2.3. A non-square field of
            %   view is centred in it. The voxel is kept within the matrix.

            set(0,'units','pixels');
            guiposition = get(app.DSCmapUIFigure,'Position');
            cursorposition = get(0,'PointerLocation');

            imagepos = [310, 408];
            imsize = 320;
            Xpos = cursorposition(1)-guiposition(1)-imagepos(1);
            Ypos = cursorposition(2)-guiposition(2)-imagepos(2);

            aratio = app.td.aspectRatio;
            xsize = app.td.dimx;
            ysize = app.td.dimy;

            if aratio >= 1
                xcor = (Xpos/imsize) * xsize;
                ycor = (Ypos/imsize) * ysize * aratio - (0.5 * (aratio-1) * ysize);
            else
                xcor = (Xpos/imsize) * xsize * (1/aratio) - (0.5 * ((1/aratio)-1) * xsize);
                ycor = (Ypos/imsize) * ysize;
            end

            xcor = min(max(round(xcor),1),xsize);
            ycor = min(max(round(ycor),1),ysize);

        end % ClickedVoxelFcn


        % Shut the app down
        function CloseAppFcn(app)

            % Purpose:
            %   Shut the app down, whether the exit button or the window's close box was
            %   used, also while the app is still busy, and leave the MATLAB session as it
            %   was found.
            %
            % How it works:
            %   While app code is still running -- the startup, a step or a loop -- deleting
            %   the app would make that code fail on a deleted object. The close is then
            %   deferred: the window is hidden, what runs is asked to stop, and a timer
            %   finishes the close once no app code runs any more (DeferredCloseFcn).
            %
            %   Otherwise each step is guarded on its own, so a failure in one cannot leave
            %   the window open or the log unclosed: the log is closed, and the path, the
            %   folder and the warning state MATLAB had are put back.
            %
            %   The figure is deleted before the app. Deleting only the app from the close
            %   box left the figure registered as the running app, and the app could then
            %   not be started again. Both go before the app's folders leave the search
            %   path: deleting the figure runs the app's destructor, which MATLAB cannot
            %   find once they are gone. What the restoring needs is kept first.
            %
            % Inputs:
            %   app - the running app
            %
            % Output:
            %   none - the app is closed, or its close is scheduled

            % Still busy: stop the fit or registration and close once it has finished
            if app.AppCodeRunningFcn

                if ~app.closeRequested
                    app.closeRequested = true;
                    app.abortFitFlag = true;
                    app.abortRegFlag = true;
                    app.TextMessage('Closing when the current step has finished ...');
                    app.DSCmapUIFigure.Visible = 'off';
                    app.closeTimer = timer('Name', 'DSCmappCloseTimer', ...
                        'ExecutionMode', 'fixedSpacing', 'Period', 0.5, 'StartDelay', 0.5, ...
                        'TasksToExecute', 7200, ...
                        'TimerFcn', @(t,~) isvalid(app) && app.DeferredCloseFcn(t));
                    start(app.closeTimer);
                end

                return

            end

            % Close the log file
            try
                app.TextMessage('Closing DSCmapp ...');
                if app.logFile > 2
                    fclose(app.logFile);
                end
                app.logFile = -1;
            catch
            end

            % Keep what the restoring needs; the app is deleted before it is used
            appRoot = app.appFolder;
            startFolder = app.oldFolder;
            startWarnings = app.oldWarningState;

            % Delete the figure, then the app, while the app folders are still on the
            % search path: deleting the figure runs the app's destructor
            try
                delete(app.DSCmapUIFigure);
            catch
            end
            try
                delete(app);
            catch
            end

            % Remove the app folders from the search path, and return to the folder MATLAB
            % was in
            if ~isdeployed
                try
                    rmpath(genpath(appRoot));
                    cd(startFolder);
                catch
                end
            end

            % Restore the warning state MATLAB had
            try
                if ~isempty(startWarnings)
                    warning(startWarnings);
                end
            catch
            end

        end % CloseAppFcn


        % Finish a close that had to wait
        function done = DeferredCloseFcn(app, t)

            % Purpose:
            %   Complete a close that was requested while app code was still running.
            %
            % How it works:
            %   The close timer calls this twice a second. While app code still runs
            %   nothing else happens. Once it has stopped, one more tick is waited, so
            %   that the callback that ended the step has returned as well, and the app is
            %   then closed the normal way.
            %
            % Inputs:
            %   app - the app being closed
            %   t   - the close timer
            %
            % Output:
            %   done - always true, since the timer's function handle needs a value

            done = true;

            try

                % Keep the window hidden; a dialog that returned may have raised it
                app.DSCmapUIFigure.Visible = 'off';

                % Still running: wait for the next tick
                if app.AppCodeRunningFcn
                    t.UserData = 0;
                    return
                end

                % Idle for the first time: wait one more tick
                if isempty(t.UserData) || t.UserData < 1
                    t.UserData = 1;
                    return
                end

                % Idle twice in a row: close
                stop(t);
                delete(t);
                app.closeTimer = [];
                app.CloseAppFcn;

            catch

                % A timer that fails is removed rather than left firing
                try
                    stop(t);
                    delete(t);
                catch
                end

            end

        end % DeferredCloseFcn


        % Check whether app code is running
        function running = AppCodeRunningFcn(app)

            % Purpose:
            %   Tell whether closing the app now would break code that is still running.
            %
            % How it works:
            %   Any method of the app still on the call stack -- a callback, the startup,
            %   a loop -- is running underneath the close request, or underneath the timer
            %   that asks. The closing methods themselves do not count, and neither do
            %   anonymous functions, whose names carry the method that created them.
            %
            %   Where the stack cannot be read, the BUSY status that every step sets is
            %   used instead.
            %
            % Inputs:
            %   app - the running app
            %
            % Output:
            %   running - true while app code other than the closing methods runs

            running = app.busyFlag;

            % The methods that do the closing
            closing = ["CloseAppFcn", "DeferredCloseFcn", "AppCodeRunningFcn", "ExitButtonPushed"];

            % The app's own methods on the call stack
            try
                names = string({dbstack.name});
                mine = startsWith(names, "DSCmapp.");
                if any(mine)
                    running = any(mine & ~contains(names, "@(") & ~contains(names, closing));
                end
            catch
            end

        end % AppCodeRunningFcn


    end % methods


    % ----------------------------------------------
    %
    % Public functions, available outside the app
    %
    % ----------------------------------------------


    methods (Access = public)


        % Text messages in the app
        function TextMessage(app, message)

            % Every message goes to the log as well, with the time of day, in its
            % own guard so that it reaches the file even when the pane cannot take
            % it, as while the interface is being torn down
            try
                if app.logFile > 2
                    fprintf(app.logFile, '%s  %s\n', char(datetime('now','Format','HH:mm:ss')), ...
                        char(strjoin(string(message), ' ')));
                end
            catch
            end

            try
                app.MessageBox.Value = [string(message) ; string(app.MessageBox.Value)];
                app.MessageBox.Value = app.MessageBox.Value(1:min(50,end),1);
                drawnow;
            catch
            end

        end % TextMessage


        % Status icon
        function SetStatus(app,status)

            % BUSY marks a running step until OK or ERROR ends it; a WARNING leaves the
            % flag as it was. A close request waits for the flag to clear
            if status == 3
                app.busyFlag = true;
            elseif status == 0 || status == 2
                app.busyFlag = false;
            end

            switch status

                case 0
                    app.StatusImage.ImageSource = 'statusokay.gif';
                    app.StatusLabel.FontColor = app.green;
                    app.StatusLabel.FontWeight = 'bold';
                    app.StatusLabel.FontSize = 14;
                    app.StatusLabel.Text = 'OK';

                case 1
                    app.StatusImage.ImageSource = 'statuswarning.gif';
                    app.StatusLabel.FontColor = app.orange;
                    if app.AutoSoundsCheckBox.Value
                        sound(app.warningSound,48000);
                    end
                    app.StatusLabel.FontWeight = 'bold';
                    app.StatusLabel.FontSize = 11;
                    app.StatusLabel.Text = 'WARNING';

                case 2
                    app.StatusImage.ImageSource = 'statuserror.gif';
                    app.StatusLabel.FontColor = app.brightred;
                    if app.AutoSoundsCheckBox.Value
                        sound(app.errorSound,48000);
                    end
                    app.StatusLabel.FontWeight = 'bold';
                    app.StatusLabel.FontSize = 12;
                    app.StatusLabel.Text = 'ERROR';

                case 3
                    app.StatusImage.ImageSource = 'busy.gif';
                    app.StatusLabel.FontColor = app.blue;
                    app.StatusLabel.FontWeight = 'bold';
                    app.StatusLabel.FontSize = 12;
                    app.StatusLabel.Text = 'BUSY';

            end

            drawnow;

        end


        % Load a DICOM series
        function ok = LoadDataset(app, folder)

            % Purpose:
            %   Loads a folder of DSC DICOM files, or refuses it and says why.
            %
            % How it works:
            %   The work the load button does after the folder dialog, public so that
            %   a script or a test can drive the app without one. The analysis starts
            %   at the first dynamic of the fit parameters, or at the last dynamic but
            %   one when the series is shorter. A refused series leaves the dataset as
            %   it was, and the reason is shown in the message pane.
            %
            % Inputs:
            %   folder - the folder holding the .dcm files of one series
            %
            % Outputs:
            %   ok - true when the series was loaded

            ok = false;

            app.SetLights([3,0,0,0,0,0]);
            app.SetButtons([0,0,0,0,0,0,0]);
            app.SetFitButtons(0);
            app.SetImageSelectionButtons(0);
            app.SetSegmentationButtons(0);
            app.SetMapButtons(0);
            app.SetStatus(3);

            try

                app.validDataFlag = false;
                app.validRegFlag = false;
                app.validFitFlag = false;

                app.TextMessage(strcat("Loading ", folder, " ..."));
                [valid, reason] = app.td.importDicom(folder, AppReporter(app));

                if ~valid
                    ME = MException('DSCmapp:InvalidData', '%s', "ERROR: " + reason + " ...");
                    throw(ME);
                end

                fitParams = gatherFitParams(app);
                app.td.setFirstDynamic(min(fitParams.firstDynamic, app.td.nd - 1));
                app.dicomImportFolder = char(folder);
                app.MirrorDataOutputsFcn;
                app.SetWidgetLimitsFcn;
                app.ResetForNewImagesFcn;
                app.validDataFlag = true;
                app.TextMessage(sprintf("Dataset: %d dynamics from dynamic %d, %d slices, %d x %d ...", ...
                    app.td.nr, app.td.firstDynamic, app.td.ns, app.td.dimx, app.td.dimy));

                % The middle slice, at the first dynamic of the analysis
                slice = max(1, round(app.td.ns/2));
                app.SlicesEditField.Value = slice;
                app.SlicesSlider.Value = slice;
                app.PlotImageFig;

                app.SetFitButtons(1);
                app.SetImageSelectionButtons(1);
                app.SetSegmentationButtons(1);
                app.SetLights([1,0,0,0,0,0]);
                app.SetButtons([1,1,1,1,0,0,1]);
                app.SetStatus(0);
                ok = true;

            catch ME

                app.TextMessage(ME.message);
                if ~startsWith(string(ME.message), "ERROR")
                    app.TextMessage('ERROR: Something went wrong while loading the data ...');
                end
                app.validDataFlag = false;
                app.SetFitButtons(0);
                app.SetLights([0,0,0,0,0,0]);
                app.SetButtons([1,0,0,0,0,0,1]);
                app.SetStatus(2);

            end

        end % LoadDataset


        % Search the AIF around a voxel
        function ok = SearchAIF(app, xcor, ycor, slice)

            % Purpose:
            %   Does what a click in the image does: puts the cross-hair on the voxel,
            %   shows its signal, and searches the arterial input function around it.
            %
            % How it works:
            %   Public so that a script or a test can choose the AIF without a mouse.
            %   The search takes a few seconds. When it finds nothing the cross-hair
            %   stays, a warning says to try another point, and the AIF found before is
            %   kept and drawn again. After a fit, the fit evaluation shows the clicked
            %   voxel.
            %
            % Inputs:
            %   xcor, ycor - the voxel
            %   slice      - the slice it is in
            %
            % Outputs:
            %   ok - true when an AIF was found

            ok = false;

            if ~app.validDataFlag || app.busyFlag
                return
            end

            app.clickedPoint = [xcor ycor slice];
            app.XViewField.Value = xcor;
            app.YViewField.Value = ycor;
            app.PlotImageFig;
            app.PlotSignalFcn;
            app.SetStatus(3);

            try

                fitParams = gatherFitParams(app);
                aif = dscmapp.fit.findAIF(app.td, fitParams, [xcor ycor], slice, AppReporter(app));
                app.td.setAIF(aif);
                app.TextMessage(sprintf("AIF of %d voxels around (%d, %d) in slice %d ...", size(aif.voxels,1), xcor, ycor, slice));
                app.PlotImageFig;
                app.PlotAIFFcn;
                app.SetStatus(0);
                ok = true;

            catch ME

                % The AIF found before stays in use, so it is shown again
                app.TextMessage(ME.message);
                app.TextMessage('WARNING: AIF search failed, try a different region ...');
                app.PlotAIFFcn;
                app.SetStatus(1);
                app.SetStatus(0);

            end

            app.PlotFitFcn;

        end % SearchAIF


    end % methods (Access = public)


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)

            % The window is already showing, so closing it during the startup waits for
            % the startup to finish, as it does during any other step
            app.busyFlag = true;
            app.DSCmapUIFigure.CloseRequestFcn = @(~,~) app.CloseAppFcn;

            % Disable unnecessary warnings
            clc;
            app.oldWarningState = warning;   % put back when the app closes
            warning off;

            % Set app theme to light
            theme(app.DSCmapUIFigure,"light")

            % Main window noname
            app.DSCmapUIFigure.Name = '';

            % Add subfolders to search folder
            if ~isdeployed
                app.oldFolder = pwd;
                info = dir(which('DSCmapp.mlapp'));
                app.appFolder = info.folder;
                cd(app.appFolder);
                addpath(genpath(pwd));

                % Retired copies live in backup/ and must not be reachable.
                % genpath walks folders in alphabetical order, so backup comes
                % before functions and an old copy there would be found first.
                if isfolder(fullfile(pwd,'backup'))
                    rmpath(genpath(fullfile(pwd,'backup')));
                end
            end

            % The dataset this session works on. Created here rather than
            % earlier because @dscData sits beside the app, so the class is only
            % reachable once the paths above are set.
            app.td = dscData;

            % The log first, so that anything that goes wrong from here on is
            % recorded, then the settings the interface starts from. Neither
            % raises: without a log the session is not logged, and a damaged
            % settings file is repaired or replaced.
            [app.logFileName, app.logFile] = dscmapp.io.makeLogFile(app.appVersion, AppReporter(app));
            app.TextMessage(strcat("DSCmapp ", app.appVersion, ", log file = ", app.logFileName, " ..."));
            app.jsonPar = dscmapp.io.readSettingsFile(app.appVersion, AppReporter(app));
            app.dicomImportFolder = app.jsonPar.dicomImportPath;
            app.dicomExportFolder = app.jsonPar.defaultExportPath;
            app.gifExportFolder = app.jsonPar.defaultExportPath;

            % Initialize user interface
            app.InitializeUI;

            % Set app colors
            app.ColorsButtonGroupSelectionChanged;

            % PC or MAC
            try
                if ismac()
                    [~,result] = system('uname -v');
                    if any(strfind(result,'ARM64'))
                        app.TextMessage('Apple arm64 version ...');
                    else
                        app.TextMessage('Apple Intel version ...');
                    end
                else
                    app.TextMessage('PC Intel version ...');
                end
            catch ME
                app.TextMessage(ME.message);
            end

            % Elastix. The binary is located with resourcePath rather than which(),
            % because a compiled app has no MATLAB path.
            try
                if ismac || isunix
                    result = getenv('PATH');
                    for extra = {'/usr/local/bin','/usr/local/lib'}
                        if ~contains(result, extra{1})
                            result = [result pathsep extra{1}]; %#ok<AGROW>
                        end
                    end
                    setenv('PATH', result);
                else
                    elastixExe = dscmapp.util.resourcePath('elastix.exe');
                    if ~isempty(elastixExe)
                        elastixFolder = fileparts(elastixExe);
                        result = getenv('PATH');
                        if ~contains(result, elastixFolder)
                            setenv('PATH', [result pathsep elastixFolder]);
                        end
                    end
                end
            catch ME
                app.TextMessage(ME.message);
            end

            % Whether elastix can actually be run, reported once
            app.elastixDetectedFlag = false;

            try
                probe = dscmapp.util.useBundledElastix('elastix --version');
                [elastixStatus, elastixReply] = system(probe);
                app.elastixDetectedFlag = elastixStatus == 0 && contains(elastixReply,'version');
            catch
            end

            if app.elastixDetectedFlag
                app.TextMessage('Elastix detected ...');
            else
                app.TextMessage('WARNING: elastix not available; registration will use Matlab imregister ...');
            end

            % Old logs, now that the number to keep is known
            dscmapp.io.pruneLogFiles(app.logFileName, app.jsonPar.logFilesToKeep, AppReporter(app));

            % Check version
            app.CheckVersionFnc;

            % OK
            app.SetStatus(0);
            app.SetButtons([1,0,0,0,0,0,1]);
            app.TextMessage("Ready ...");

        end

        % Selection changed function: ColorsButtonGroup
        function ColorsButtonGroupSelectionChanged(app, event)

            selectedButton = app.ColorsButtonGroup.SelectedObject;

            switch lower(selectedButton.Text)

                % COLORS: backgroundColor, fontColor, boxColor, gaugeColor, gaugeBackgroundColor, figureColor, movieFigureColor, gridColor, autoRecoColor, manualRecoColor

                case "classic"
                    guiColors = {app.guiGrey, app.guiBlack, app.guiDarkGrey, app.blue, app.guiWhite, app.guiWhite, app.guiMiddleGrey, app.guiDarkGrey, app.lightblue, app.guiGrey};

                case "black/white"
                    guiColors = {app.guiBlack, app.guiWhite, app.guiDarkGrey, app.blue, app.guiDarkGrey, app.guiGrey, app.guiDarkGrey, app.guiDarkGrey, app.guiBlackBlue, app.guiBlack};

            end

            app.SetAppColorsFcn(guiColors);

        end

        % Button pushed function: LoadDCMfileButton
        function LoadDicomFileButtonPushed(app, event)

            % Select a folder of DSC DICOM files
            f = figure('CloseRequestFcn','','Position',[-100 -100 0 0]); % Needed to bring uigetdir in front
            folder = uigetdir(app.dicomImportFolder,'DICOM import folder ...');
            delete(f);

            if ~app.closeRequested
                figure(app.DSCmapUIFigure);
            end

            if ~ischar(folder)
                app.TextMessage('User cancel ...');
                return
            end

            app.LoadDataset(folder);

        end

        % Value changed function: DynamicsEditField, SlicesEditField
        function SlicesEditFieldValueChanged(app, event)

            if ~app.validDataFlag
                return
            end

            app.SlicesSlider.Value = app.SlicesEditField.Value;
            app.DynamicsSlider.Value = app.DynamicsEditField.Value;
            app.PlotImageFig;

        end

        % Value changed function: SlicesSlider
        function SlicesSliderValueChanged(app, event)

            app.SlicesEditField.Value = min(round(app.SlicesSlider.Value), app.SlicesEditField.Limits(2));
            app.SlicesEditFieldValueChanged;

        end

        % Value changed function: DynamicsSlider
        function DynamicsSliderValueChanged(app, event)

            app.DynamicsEditField.Value = min(round(app.DynamicsSlider.Value), app.DynamicsEditField.Limits(2));
            app.SlicesEditFieldValueChanged;

        end

        % Button pushed function: MaskButton
        function MaskButtonPushed(app, event)

            if app.validDataFlag

                % Indicate busy
                previousLamps = {app.Lamp1.Color, app.Lamp2.Color, app.Lamp3.Color, app.Lamp4.Color, ...
                    app.Lamp5.Color, app.Lamp6.Color};
                app.SetLights([2,2,3,2,2,2]);
                app.SetButtons([0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(0);
                app.SetStatus(3);
                app.TextMessage('Thresholding images ...');

                % A failure halfway would leave a mask that no longer matches its
                % thresholds, so both are put back as they were
                previousMask = app.td.mask;
                previousThreshold = app.td.threshold;
                exported = double(app.validFitFlag);

                try

                    % An Otsu threshold per slice, on the first image of the analysis
                    for slice = 1:app.td.ns
                        im = reshape(app.td.images(1,slice,:,:), app.td.dimx, app.td.dimy);
                        app.td.threshold(slice) = graythresh(mat2gray(im))*max(im(:));
                        app.td.mask(slice,:,:) = reshape(dscmapp.fit.applyThreshold(im, app.td.threshold(slice)), 1, app.td.dimx, app.td.dimy);
                    end

                    app.maskBeforeOperation = [];
                    app.maskAfterOperation = [];

                    % Display the mask in the image and on the maps
                    app.PlotImageFig;
                    app.PlotMaps;

                    app.SetLights([2,2,1,2,2,2]);
                    app.SetButtons([1,1,1,1,exported,exported,1]);
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(exported);
                    app.SetStatus(0);

                catch ME

                    app.td.mask = previousMask;
                    app.td.threshold = previousThreshold;
                    app.TextMessage(ME.message);
                    app.TextMessage('ERROR: Something went wrong while thresholding the images ...');
                    for k = 1:numel(previousLamps)
                        app.("Lamp" + k).Color = previousLamps{k};
                    end
                    app.Lamp3.Color = app.red;
                    app.SetButtons([1,1,1,1,exported,exported,1]);
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(exported);
                    app.SetStatus(2);

                end

            end

        end

        % Value changed function: thresholdEditField
        function ThresholdEditFieldValueChanged(app, event)

            % The threshold of the slice shown, and its mask, on the first image of
            % the analysis

            if ~app.validDataFlag
                return
            end

            % A failure halfway would leave a mask that is partly changed, so the mask
            % and thresholds are put back as they were
            previousMask = app.td.mask;
            previousThreshold = app.td.threshold;

            try

                slice = app.SlicesEditField.Value;
                app.td.threshold(slice) = app.thresholdEditField.Value;
                app.ThresholdSlider.Value = app.thresholdEditField.Value;

                im = reshape(app.td.images(1,slice,:,:), app.td.dimx, app.td.dimy);
                app.td.mask(slice,:,:) = reshape(dscmapp.fit.applyThreshold(im, app.td.threshold(slice)), 1, app.td.dimx, app.td.dimy);

                app.PlotImageFig;
                app.PlotMaps;

            catch ME

                app.td.mask = previousMask;
                app.td.threshold = previousThreshold;
                app.TextMessage(ME.message);
                app.TextMessage('ERROR: the mask could not be changed ...');
                app.SetStatus(2);

            end

        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)

            app.thresholdEditField.Value = app.ThresholdSlider.Value;
            app.ThresholdEditFieldValueChanged;

        end

        % Button pushed function: ErodeButton
        function ErodeButtonPushed(app, event)

            app.MaskOperationFcn("erode");

        end

        % Button pushed function: DilateButton
        function DilateButtonPushed(app, event)

            app.MaskOperationFcn("dilate");

        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)

            app.MaskOperationFcn("close");

        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)

            app.MaskOperationFcn("open");

        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)

            % Only the last operation is undone, and only while the mask is still the
            % one it left, so a threshold or a mask made since then is not overwritten
            if ~isempty(app.maskAfterOperation) && isequal(app.td.mask == 1, app.maskAfterOperation)
                app.td.mask = double(app.maskBeforeOperation);
                app.TextMessage('The last mask operation is undone ...');
                app.PlotImageFig;
                app.PlotMaps;
            else
                app.TextMessage('WARNING: the mask has changed since the last operation, nothing to undo ...');
            end

            app.maskBeforeOperation = [];
            app.maskAfterOperation = [];
            app.UndoButton.Enable = 'off';

        end

        % Value changed function: WindowEditField
        function WindowEditFieldValueChanged(app, event)

            app.WindowSlider.Value = app.WindowEditField.Value;
            app.AutoLWButton.Value = 0;
            app.PlotImageFig;

        end

        % Value changed function: WindowSlider
        function WindowSliderValueChanged(app, event)

            app.WindowEditField.Value = round(app.WindowSlider.Value);
            app.AutoLWButton.Value = 0;
            app.PlotImageFig;

        end

        % Value changed function: LevelEditField
        function LevelEditFieldValueChanged(app, event)

            app.LevelSlider.Value = app.LevelEditField.Value;
            app.AutoLWButton.Value = 0;
            app.PlotImageFig;

        end

        % Value changed function: LevelSlider
        function LevelSliderValueChanged(app, event)

            app.LevelEditField.Value = round(app.LevelSlider.Value);
            app.AutoLWButton.Value = 0;
            app.PlotImageFig;

        end

        % Value changed function: AutoLWButton
        function AutoLWButtonValueChanged(app, event)

            if app.AutoLWButton.Value == 1
                app.PlotImageFig;
            end

        end

        % Button pushed function: RegisterButton
        function RegisterButtonPushed(app, event)

            if app.validDataFlag

                % What a failure puts back: the images, and everything setImages clears,
                % the flags that describe them, and the lamps
                previous.images = app.td.images;
                previous.fields = {};

                for name = [dscmapp.Constants.MAP_FIELDS, {'method', 'conc', 'residual', 's0', 'fwhm', 'mask', 'threshold', ...
                        'aifPoint', 'aifVoxels', 'aifRegion', 'aif', 'aifFit', 'mapAif'}]
                    previous.fields(end+1,:) = {name{1}, app.td.(name{1})};
                end

                previous.validRegFlag = app.validRegFlag;
                previous.validFitFlag = app.validFitFlag;
                previous.lamps = {app.Lamp1.Color, app.Lamp2.Color, app.Lamp3.Color, app.Lamp4.Color, ...
                    app.Lamp5.Color, app.Lamp6.Color};

                app.validRegFlag = false;
                app.validFitFlag = false;

                % Indicate busy
                app.SetLights([1,3,0,0,0,0]);
                app.SetButtons([0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(0);
                app.SetStatus(3);
                app.abortRegFlag = false;
                app.AbortRegButton.Enable = 'on';

                try

                    % Every dynamic to the first dynamic of the analysis, per slice
                    reporter = AppReporter(app,'registration');
                    regParams = gatherRegParams(app);
                    app.LogParamsFcn('registration', regParams);
                    [images, usedFallback] = dscmapp.reg.registerImages(app.td.images, 1, regParams, reporter);

                    app.td.setImages(images);

                    if usedFallback
                        app.TextMessage("WARNING: elastix was not available; registration used Matlab imregister ...");
                    end

                    if reporter.aborted()
                        app.TextMessage("WARNING: registration aborted, the images are partly registered ...");
                    end

                    app.validRegFlag = true;
                    app.maskBeforeOperation = [];
                    app.maskAfterOperation = [];
                    app.ClearMapsFcn;
                    app.PlotImageFig;

                    % Reset buttons
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetFitButtons(1);
                    app.AbortRegButton.Enable = 'off';
                    app.SetLights([1,1,0,0,0,0]);
                    app.SetButtons([1,1,1,1,0,0,1]);
                    app.SetStatus(0);

                catch ME

                    app.TextMessage(ME.message);
                    app.TextMessage('ERROR: Something went wrong during registration ...');

                    % Everything back as it was before the step, so a registration that
                    % failed halfway leaves nothing half done, and the steps whose results
                    % are intact stay usable
                    app.td.images = previous.images;

                    for k = 1:size(previous.fields, 1)
                        app.td.(previous.fields{k,1}) = previous.fields{k,2};
                    end

                    app.validRegFlag = previous.validRegFlag;
                    app.validFitFlag = previous.validFitFlag;

                    for k = 1:numel(previous.lamps)
                        app.("Lamp" + k).Color = previous.lamps{k};
                    end

                    app.Lamp2.Color = app.red;

                    try
                        app.PlotImageFig;
                        app.PlotMaps;
                    catch
                    end

                    fitted = double(app.validFitFlag);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetFitButtons(1);
                    app.AbortRegButton.Enable = 'off';
                    app.SetMapButtons(fitted);
                    app.SetButtons([1,1,1,1,fitted,fitted,1]);
                    app.SetStatus(2);

                end

            end

        end

        % Button pushed function: AbortRegButton
        function AbortRegButtonPushed(app, event)

            app.abortRegFlag = true;
            drawnow;

        end

        % Image clicked function: RawImage
        function RawImageClicked(app, event)

            % The AIF is searched around the voxel clicked, in the slice shown
            if app.validDataFlag
                [xcor, ycor] = app.ClickedVoxelFcn;
                app.SearchAIF(xcor, ycor, app.SlicesEditField.Value);
            end

        end

        % Button pushed function: FitDSCButton
        function FitDSCButtonPushed(app, event)

            if app.validDataFlag

                if isempty(app.clickedPoint)
                    app.TextMessage('WARNING: click on an artery in the image first, the AIF is searched around it ...');
                    app.SetStatus(1);
                    return
                end

                % What a failure puts back: the flag that describes the maps, the
                % scales the fit sets, and the lamps; the maps themselves are only
                % stored once the fit has finished
                previous.validFitFlag = app.validFitFlag;
                previous.scales = zeros(numel(dscmapp.Constants.MAP_WIDGETS), 2);

                for k = 1:numel(dscmapp.Constants.MAP_WIDGETS)
                    prefix = dscmapp.Constants.MAP_WIDGETS{k};
                    previous.scales(k,:) = [app.(prefix + "ScaleMinEditField").Value, app.(prefix + "ScaleEditField").Value];
                end

                previous.lamps = {app.Lamp1.Color, app.Lamp2.Color, app.Lamp3.Color, app.Lamp4.Color, ...
                    app.Lamp5.Color, app.Lamp6.Color};

                app.SetLights([2,2,2,3,0,0]);
                app.SetButtons([0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(0);
                app.SetStatus(3);
                app.TextMessage('Fitting DSC maps ...');
                app.validFitFlag = false;
                app.abortFitFlag = false;
                drawnow;

                try

                    % One struct describes this fit, gathered once, so that nothing
                    % below the app reads a widget; the AIF is searched around the
                    % voxel last clicked
                    fitParams = gatherFitParams(app);
                    point = app.clickedPoint;
                    app.LogParamsFcn('fit', setfield(fitParams, 'aifPoint', point)); %#ok<SFLD>

                    result = dscmapp.fit.fitMaps(app.td, fitParams, point(1:2), point(3), AppReporter(app,'fit'));
                    app.td.setFitResult(result);
                    app.validFitFlag = true;

                    % The AIF and the fit evaluation
                    app.PlotImageFig;
                    app.PlotAIFFcn;
                    app.PlotFitFcn;

                    % The maps, at the middle slice, with automatic scales
                    app.ChangeMapSlices(round(app.td.ns/2));

                    for k = 1:numel(dscmapp.Constants.MAP_WIDGETS)
                        app.AutoScaleFcn(k);
                    end

                    app.PlotMaps;
                    app.TextMessage('Finished ...');

                    % Reset buttons
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetFitButtons(1);
                    app.SetLights([2,2,2,1,0,0]);
                    app.SetMapButtons(1);
                    app.SetButtons([1,1,1,1,1,1,1]);
                    app.SetStatus(0);

                catch ME

                    app.TextMessage(ME.message);
                    app.TextMessage("ERROR: Something went wrong during fitting ...");

                    % An earlier fit that was complete stays usable
                    app.validFitFlag = previous.validFitFlag;

                    for k = 1:numel(dscmapp.Constants.MAP_WIDGETS)
                        prefix = dscmapp.Constants.MAP_WIDGETS{k};
                        app.(prefix + "ScaleMinEditField").Value = previous.scales(k,1);
                        app.(prefix + "ScaleEditField").Value = previous.scales(k,2);
                    end

                    for k = 1:numel(previous.lamps)
                        app.("Lamp" + k).Color = previous.lamps{k};
                    end

                    app.Lamp4.Color = app.red;
                    app.FitProgressGauge.Value = 0;

                    try
                        app.PlotImageFig;
                        app.PlotMaps;
                    catch
                    end

                    fitted = double(app.validFitFlag);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetFitButtons(1);
                    app.SetMapButtons(fitted);
                    app.SetButtons([1,1,1,1,fitted,fitted,1]);
                    app.SetStatus(2);

                end

            end

        end

        % Value changed function: FirstDynamicEditField
        function FirstDynamicEditFieldValueChanged(app, event)

            % The analysis starts at another dynamic: the images are taken afresh from
            % the imported data, so a registration, the mask, the AIF and the maps are
            % cleared, as in version 1.2.3

            if ~app.validDataFlag
                return
            end

            try

                if app.validFitFlag || app.validRegFlag
                    app.TextMessage('WARNING: the registration, the mask, the AIF and the maps are cleared ...');
                end

                fitParams = gatherFitParams(app);
                app.td.setFirstDynamic(min(fitParams.firstDynamic, app.td.nd - 1));
                app.validRegFlag = false;
                app.validFitFlag = false;
                app.MirrorDataOutputsFcn;
                slice = app.SlicesEditField.Value;
                app.SetWidgetLimitsFcn;
                app.SlicesEditField.Value = slice;
                app.SlicesSlider.Value = slice;
                app.ResetForNewImagesFcn;
                app.PlotImageFig;

                app.SetSegmentationButtons(1);
                app.SetMapButtons(0);
                app.SetLights([1,0,0,0,0,0]);
                app.SetButtons([1,1,1,1,0,0,1]);
                app.TextMessage(sprintf("The analysis starts at dynamic %d, %d dynamics ...", app.td.firstDynamic, app.td.nr));

            catch ME

                app.TextMessage(ME.message);
                app.TextMessage('ERROR: the first dynamic could not be changed ...');
                app.SetStatus(2);

            end

        end

        % Value changed function: MapSlicesEditField1
        function MapSlicesEditField1ValueChanged(app, event)

            app.ChangeMapSlices(app.MapSlicesEditField1.Value);
            app.PlotMaps;

        end

        % Value changed function: MapSlicesEditField2
        function MapSlicesEditField2ValueChanged(app, event)

            app.ChangeMapSlices(app.MapSlicesEditField2.Value);
            app.PlotMaps;

        end

        % Value changed function: MapSlicesEditField3
        function MapSlicesEditField3ValueChanged(app, event)

            app.ChangeMapSlices(app.MapSlicesEditField3.Value);
            app.PlotMaps;

        end

        % Value changed function: MapSlicesSlider1
        function MapSlicesSlider1ValueChanged(app, event)

            app.ChangeMapSlices(app.MapSlicesSlider1.Value);
            app.PlotMaps;

        end

        % Value changed function: MapSlicesSlider2
        function MapSlicesSlider2ValueChanged(app, event)

            app.ChangeMapSlices(app.MapSlicesSlider2.Value);
            app.PlotMaps;

        end

        % Value changed function: MapSlicesSlider3
        function MapSlicesSlider3ValueChanged(app, event)

            app.ChangeMapSlices(app.MapSlicesSlider3.Value);
            app.PlotMaps;

        end

        % Value changed function: CBFScaleEditField, CBVScaleEditField, 
        % ...and 4 other components
        function MapScaleEditFieldValueChanged(app, event)

            % The top of a scale has to stay above its bottom
            k = app.MapOfScaleFieldFcn(event.Source, "ScaleEditField");

            if k > 0 && event.Source.Value <= app.(dscmapp.Constants.MAP_WIDGETS{k} + "ScaleMinEditField").Value
                event.Source.Value = event.PreviousValue;
                app.TextMessage("WARNING: the " + dscmapp.Constants.MAP_NAMES{k} + " scale maximum has to lie above the minimum ...");
                return
            end

            app.PlotMaps;

        end

        % Value changed function: CBFScaleMinEditField, 
        % ...and 5 other components
        function MapScaleMinEditFieldValueChanged(app, event)

            % The bottom of a scale has to stay below its top
            k = app.MapOfScaleFieldFcn(event.Source, "ScaleMinEditField");

            if k > 0 && event.Source.Value >= app.(dscmapp.Constants.MAP_WIDGETS{k} + "ScaleEditField").Value
                event.Source.Value = event.PreviousValue;
                app.TextMessage("WARNING: the " + dscmapp.Constants.MAP_NAMES{k} + " scale minimum has to lie below the maximum ...");
                return
            end

            app.PlotMaps;

        end

        % Button pushed function: CBVScaleAutoButton
        function CBVScaleAutoButtonPushed(app, event)

            app.AutoScaleFcn(1);
            app.PlotMaps;

        end

        % Button pushed function: CBFScaleAutoButton
        function CBFScaleAutoButtonPushed(app, event)

            app.AutoScaleFcn(2);
            app.PlotMaps;

        end

        % Button pushed function: MTTScaleAutoButton
        function MTTScaleAutoButtonPushed(app, event)

            app.AutoScaleFcn(3);
            app.PlotMaps;

        end

        % Button pushed function: TTPScaleAutoButton
        function TTPScaleAutoButtonPushed(app, event)

            app.AutoScaleFcn(4);
            app.PlotMaps;

        end

        % Button pushed function: CBVlcScaleAutoButton
        function CBVlcScaleAutoButtonPushed(app, event)

            app.AutoScaleFcn(5);
            app.PlotMaps;

        end

        % Button pushed function: K2ScaleAutoButton
        function K2ScaleAutoButtonPushed(app, event)

            app.AutoScaleFcn(6);
            app.PlotMaps;

        end

        % Selection changed function: CBVColorMapButtonGroup
        function CBVColorMapButtonGroupSelectionChanged(app, event)

            app.SetColorMapFcn(1);
            app.PlotMaps;

        end

        % Selection changed function: CBFColorMapButtonGroup
        function CBFColorMapButtonGroupSelectionChanged(app, event)

            app.SetColorMapFcn(2);
            app.PlotMaps;

        end

        % Selection changed function: MTTColorMapButtonGroup
        function MTTColorMapButtonGroupSelectionChanged(app, event)

            app.SetColorMapFcn(3);
            app.PlotMaps;

        end

        % Selection changed function: TTPColorMapButtonGroup
        function TTPColorMapButtonGroupSelectionChanged(app, event)

            app.SetColorMapFcn(4);
            app.PlotMaps;

        end

        % Selection changed function: CBVlcColorMapButtonGroup
        function CBVlcColorMapButtonGroupSelectionChanged(app, event)

            app.SetColorMapFcn(5);
            app.PlotMaps;

        end

        % Selection changed function: K2ColorMapButtonGroup
        function K2ColorMapButtonGroupSelectionChanged(app, event)

            app.SetColorMapFcn(6);
            app.PlotMaps;

        end

        % Button pushed function: DCMNIFTIButton
        function DCMNIFTIButtonPushed(app, event)

            if app.validFitFlag

                % What a cancelled dialog or a failure puts back: the export folder, which
                % the settings may have set, and the lamp of this step
                previousFolder = app.dicomExportFolder;
                previousLamp = app.Lamp5.Color;

                % Indicate busy
                app.SetLights([2,2,2,2,3,2]);
                app.SetButtons([0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(0);
                app.SetStatus(3);

                try

                    app.TextMessage('DICOM export ...');

                    % Beside the scanner's own files when the folder is one of theirs:
                    %   base / Data / study / DICOM / series /
                    [dicomFolder, baseFolder, found] = dscmapp.io.exportFolders(app.td.dicomFolder);

                    if ~found || ~app.FolderWritableFcn(dicomFolder)
                        app.DSCmapUIFigure.Visible = 'off';
                        folder = uigetdir(app.dicomExportFolder,'Export folder ...');
                        if ~app.closeRequested
                            app.DSCmapUIFigure.Visible = 'on';
                        end
                        drawnow;
                        if ~ischar(folder)
                            ME = MException('DSCmapp:userCancel','User cancel ...');
                            throw(ME);
                        end
                        app.dicomExportFolder = folder;
                        baseFolder = folder;
                        dicomFolder = fullfile(folder, 'DICOM');
                    end

                    app.TextMessage(strcat("DICOM export folder = ", dicomFolder, " ..."));
                    mapFolder = dscmapp.io.exportDicomDSC(app.td, dicomFolder, AppReporter(app));
                    app.TextMessage(strcat("Maps written to ", mapFolder, " ..."));

                    % NIfTI of the maps
                    try
                        dscmapp.io.exportNifti(app.td, baseFolder, mapFolder, AppReporter(app));
                    catch ME
                        app.TextMessage(ME.message);
                        app.TextMessage("ERROR: NIfTI export failed ...");
                    end

                    % Success
                    app.TextMessage('DICOM export ready ...');
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetLights([2,2,2,2,1,2]);
                    app.SetMapButtons(1);
                    app.SetButtons([1,1,1,1,1,1,1]);
                    app.SetStatus(0);

                catch ME

                    % The window is shown again in case the failure came while it was hidden
                    % behind the folder dialog, and the export folder is kept
                    if ~app.closeRequested
                        app.DSCmapUIFigure.Visible = 'on';
                    end

                    app.dicomExportFolder = previousFolder;
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(1);
                    app.SetButtons([1,1,1,1,1,1,1]);

                    if strcmpi(ME.identifier, 'DSCmapp:userCancel')
                        % Cancelling the folder dialog is not a failure
                        app.TextMessage('DICOM export cancelled ...');
                        app.Lamp5.Color = previousLamp;
                        app.SetStatus(0);
                    else
                        app.TextMessage(ME.message);
                        app.TextMessage('ERROR: Something went wrong during DICOM export ...');
                        app.SetLights([2,2,2,2,0,2]);
                        app.SetStatus(2);
                    end

                end

            end

        end

        % Button pushed function: GIFButton
        function GIFButtonPushed(app, event)

            if app.validFitFlag

                % What a cancelled dialog or a failure puts back: the export folder, which
                % the settings may have set, and the lamp of this step
                previousFolder = app.gifExportFolder;
                previousLamp = app.Lamp6.Color;

                % Indicate busy
                app.SetLights([2,2,2,2,2,3]);
                app.SetButtons([0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(0);
                app.SetStatus(3);

                app.TextMessage('GIF export ...');

                try

                    % base / Data / study / GIF / when the folder is the scanner's
                    [~, baseFolder, found] = dscmapp.io.exportFolders(app.td.dicomFolder);

                    if ~found || ~app.FolderWritableFcn(baseFolder)
                        app.DSCmapUIFigure.Visible = 'off';
                        folder = uigetdir(app.gifExportFolder,'GIF export folder ...');
                        if ~app.closeRequested
                            app.DSCmapUIFigure.Visible = 'on';
                        end
                        if ~ischar(folder)
                            ME = MException('DSCmapp:userCancel','User cancel ...');
                            throw(ME);
                        end
                        app.gifExportFolder = folder;
                        baseFolder = folder;
                    end

                    app.ExportGifsFcn(baseFolder);
                    app.TextMessage('GIF export ready ...');

                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetLights([2,2,2,2,2,1]);
                    app.SetMapButtons(1);
                    app.SetButtons([1,1,1,1,1,1,1]);
                    app.SetStatus(0);

                catch ME

                    % The window is shown again in case the failure came while it was hidden
                    % behind the folder dialog, and the export folder is kept
                    if ~app.closeRequested
                        app.DSCmapUIFigure.Visible = 'on';
                    end

                    app.gifExportFolder = previousFolder;
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(1);
                    app.SetButtons([1,1,1,1,1,1,1]);

                    if strcmpi(ME.identifier, 'DSCmapp:userCancel')
                        % Cancelling the folder dialog is not a failure
                        app.TextMessage('GIF export cancelled ...');
                        app.Lamp6.Color = previousLamp;
                        app.SetStatus(0);
                    else
                        app.TextMessage(ME.message);
                        app.TextMessage('ERROR: Something went wrong during GIF export ...');
                        app.SetLights([2,2,2,2,2,0]);
                        app.SetStatus(2);
                    end

                end

            end

        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)

            % The log closed, the path restored and the app deleted
            app.CloseAppFcn;

        end

        % Window scroll wheel function: DSCmapUIFigure
        function DSCmapUIFigureWindowScrollWheel(app, event)

            % Over the maps, the scroll wheel runs through the slices, round and round

            try

                set(0,'units','pixels');
                guiposition = get(app.DSCmapUIFigure,'Position');
                cursorposition = get(0,'PointerLocation');

                if app.validFitFlag && cursorposition(1) - guiposition(1) > 828

                    if event.VerticalScrollCount > 0
                        newslice = mod(app.MapSlicesEditField1.Value, app.td.ns) + 1;
                    else
                        newslice = mod(app.MapSlicesEditField1.Value - 2, app.td.ns) + 1;
                    end

                    app.ChangeMapSlices(newslice);
                    app.PlotMaps;

                end

            catch ME

                app.TextMessage(ME.message);

            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DSCmapUIFigure = uifigure('Visible', 'off');
            app.DSCmapUIFigure.Name = 'DSCmapp';
            app.DSCmapUIFigure.Icon = fullfile(pathToMLAPP, 'DSCicon.png');
            app.DSCmapUIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @DSCmapUIFigureWindowScrollWheel, true);
            app.DSCmappingLabel = uilabel(app.DSCmapUIFigure);
            app.DSCmappingLabel.Text = 'DSC mapping';
            app.Credits = uilabel(app.DSCmapUIFigure);
            app.Credits.Tooltip = {'g.j.strijkers@amsterdamumc.nl'};
            app.Credits.Text = 'Gustav Strijkers, Amsterdam UMC, Version X.X.X';
            app.TulpLogo = uiimage(app.DSCmapUIFigure);
            app.MessageBox = uitextarea(app.DSCmapUIFigure);
            app.MessageBox.Editable = 'off';
            app.MessageBox.Tooltip = {'Application messages'};
            app.MessageBox.Value = {'Messages ....'};
            app.BackgroundBox = uitextarea(app.DSCmapUIFigure);
            app.BackgroundBox.Editable = 'off';
            app.BackgroundBox.Tooltip = {'Application status'};
            app.StatusImage = uiimage(app.DSCmapUIFigure);
            app.StatusLabel = uilabel(app.DSCmapUIFigure);
            app.StatusLabel.Text = 'STATUS';
            app.MRSImage = uiimage(app.DSCmapUIFigure);
            app.TabGroup1 = uitabgroup(app.DSCmapUIFigure);
            app.DashboardTab = uitab(app.TabGroup1);
            app.DashboardTab.Title = 'Dashboard';
            app.LoadDCMfileButton = uibutton(app.DashboardTab, 'push');
            app.LoadDCMfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDicomFileButtonPushed, true);
            app.LoadDCMfileButton.Enable = 'off';
            app.LoadDCMfileButton.Tooltip = {'Load DICOM dynamic susceptibility contrast (DSC) data'};
            app.LoadDCMfileButton.Text = 'Load DCM';
            app.Lamp1 = uilamp(app.DashboardTab);
            app.MaskButton = uibutton(app.DashboardTab, 'push');
            app.MaskButton.ButtonPushedFcn = createCallbackFcn(app, @MaskButtonPushed, true);
            app.MaskButton.Enable = 'off';
            app.MaskButton.Tooltip = {'Segment the images by a threshold; the mask is applied to the maps shown and exported, not to the fit'};
            app.MaskButton.Text = 'Mask';
            app.Lamp3 = uilamp(app.DashboardTab);
            app.FitDSCButton = uibutton(app.DashboardTab, 'push');
            app.FitDSCButton.ButtonPushedFcn = createCallbackFcn(app, @FitDSCButtonPushed, true);
            app.FitDSCButton.Enable = 'off';
            app.FitDSCButton.Tooltip = {'Start the DSC parameter fitting'};
            app.FitDSCButton.Text = 'Fit DSC';
            app.Lamp4 = uilamp(app.DashboardTab);
            app.FitProgressGauge = uigauge(app.DashboardTab, 'linear');
            app.FitProgressGauge.MajorTicks = [0 25 50 75 100];
            app.Lamp5 = uilamp(app.DashboardTab);
            app.ExitButton = uibutton(app.DashboardTab, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Enable = 'off';
            app.ExitButton.Tooltip = {'Exit the application'};
            app.ExitButton.Text = 'Exit';
            app.DCMNIFTIButton = uibutton(app.DashboardTab, 'push');
            app.DCMNIFTIButton.ButtonPushedFcn = createCallbackFcn(app, @DCMNIFTIButtonPushed, true);
            app.DCMNIFTIButton.Enable = 'off';
            app.DCMNIFTIButton.Tooltip = {'Export to DICOM'};
            app.DCMNIFTIButton.Text = 'DCM/NIFTI';
            app.FitProgressLabel = uilabel(app.DashboardTab);
            app.FitProgressLabel.Text = 'Progress';
            app.Lamp6 = uilamp(app.DashboardTab);
            app.GIFButton = uibutton(app.DashboardTab, 'push');
            app.GIFButton.ButtonPushedFcn = createCallbackFcn(app, @GIFButtonPushed, true);
            app.GIFButton.Enable = 'off';
            app.GIFButton.Tooltip = {'Export to GIF'};
            app.GIFButton.Text = 'GIF';
            app.RegisterButton = uibutton(app.DashboardTab, 'push');
            app.RegisterButton.ButtonPushedFcn = createCallbackFcn(app, @RegisterButtonPushed, true);
            app.RegisterButton.Enable = 'off';
            app.RegisterButton.Tooltip = {'Register images using Elastix or Matlab'};
            app.RegisterButton.Text = 'Register';
            app.Lamp2 = uilamp(app.DashboardTab);
            app.RegProgressGauge = uigauge(app.DashboardTab, 'linear');
            app.RegProgressGauge.MajorTicks = [0 25 50 75 100];
            app.RegProgressLabel = uilabel(app.DashboardTab);
            app.RegProgressLabel.Text = 'Progress';
            app.RegistrationDropDown = uidropdown(app.DashboardTab);
            app.RegistrationDropDown.Items = {'Translation', 'Rigid', 'Affine', 'B-Spline'};
            app.RegistrationDropDown.Enable = 'off';
            app.RegistrationDropDown.Tooltip = {'Image registration transformation method'};
            app.RegistrationDropDown.Value = 'Translation';
            app.AbortRegButton = uibutton(app.DashboardTab, 'push');
            app.AbortRegButton.ButtonPushedFcn = createCallbackFcn(app, @AbortRegButtonPushed, true);
            app.AbortRegButton.Enable = 'off';
            app.AbortRegButton.Tooltip = {'Abort the registration'};
            app.AbortRegButton.Text = 'Abort';
            app.EstimatedRegTimeViewField = uieditfield(app.DashboardTab, 'text');
            app.EstimatedRegTimeViewField.Editable = 'off';
            app.EstimatedFitTimeViewField = uieditfield(app.DashboardTab, 'text');
            app.EstimatedFitTimeViewField.Editable = 'off';
            app.TabGroup3 = uitabgroup(app.DSCmapUIFigure);
            app.rCBVrCBFTab = uitab(app.TabGroup3);
            app.rCBVrCBFTab.Title = 'rCBV / rCBF /';
            app.CBVMapFig = uiaxes(app.rCBVrCBFTab);
            app.CBVMapFig.XTick = [];
            app.CBVMapFig.YTick = [];
            app.CBFMapFig = uiaxes(app.rCBVrCBFTab);
            app.CBFMapFig.XTick = [];
            app.CBFMapFig.YTick = [];
            app.CBFImage = uiimage(app.rCBVrCBFTab);
            app.rCBVLabel = uilabel(app.rCBVrCBFTab);
            app.rCBVLabel.Tooltip = {'relative Cerebral Blood Volume'};
            app.rCBVLabel.Text = 'rCBV [-]';
            app.rCBF1sLabel = uilabel(app.rCBVrCBFTab);
            app.rCBF1sLabel.Tooltip = {'relative Cerebral Blood Flow'};
            app.rCBF1sLabel.Text = 'rCBF [1/s]';
            app.CBVImage = uiimage(app.rCBVrCBFTab);
            app.MapImageSelectionPanel1 = uipanel(app.rCBVrCBFTab);
            app.MapImageSelectionPanel1.Title = 'Image selection';
            app.MapSlicesSlider1 = uislider(app.MapImageSelectionPanel1);
            app.MapSlicesSlider1.Limits = [1 200];
            app.MapSlicesSlider1.MajorTicks = [];
            app.MapSlicesSlider1.ValueChangedFcn = createCallbackFcn(app, @MapSlicesSlider1ValueChanged, true);
            app.MapSlicesSlider1.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.MapSlicesSlider1.Tooltip = {'Slice number slider'};
            app.MapSlicesSlider1.Value = 1;
            app.MapSlicesEditFieldLabel1 = uilabel(app.MapImageSelectionPanel1);
            app.MapSlicesEditFieldLabel1.Text = 'slice';
            app.MapSlicesEditField1 = uieditfield(app.MapImageSelectionPanel1, 'numeric');
            app.MapSlicesEditField1.Limits = [1 Inf];
            app.MapSlicesEditField1.ValueDisplayFormat = '%.0f';
            app.MapSlicesEditField1.ValueChangedFcn = createCallbackFcn(app, @MapSlicesEditField1ValueChanged, true);
            app.MapSlicesEditField1.Tooltip = {'Slice number'};
            app.MapSlicesEditField1.Value = 1;
            app.CBVColorMapButtonGroup = uibuttongroup(app.rCBVrCBFTab);
            app.CBVColorMapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CBVColorMapButtonGroupSelectionChanged, true);
            app.CBVColorMapButtonGroup.Title = 'Color map';
            app.CBVgrayButton = uiradiobutton(app.CBVColorMapButtonGroup);
            app.CBVgrayButton.Text = 'gray';
            app.CBVgrayButton.Interpreter = 'html';
            app.CBVlipariButton = uiradiobutton(app.CBVColorMapButtonGroup);
            app.CBVlipariButton.Text = 'lipari';
            app.CBVlipariButton.Interpreter = 'html';
            app.CBVwinterButton = uiradiobutton(app.CBVColorMapButtonGroup);
            app.CBVwinterButton.Text = 'winter';
            app.CBVwinterButton.Interpreter = 'html';
            app.CBVparulaButton = uiradiobutton(app.CBVColorMapButtonGroup);
            app.CBVparulaButton.Text = 'parula';
            app.CBVparulaButton.Interpreter = 'html';
            app.CBVnaviaButton = uiradiobutton(app.CBVColorMapButtonGroup);
            app.CBVnaviaButton.Text = 'navia';
            app.CBVnaviaButton.Interpreter = 'html';
            app.CBVnaviaButton.Value = true;
            app.CBVScaleEditField = uieditfield(app.CBVColorMapButtonGroup, 'numeric');
            app.CBVScaleEditField.Limits = [0.01 Inf];
            app.CBVScaleEditField.ValueDisplayFormat = '%.2f';
            app.CBVScaleEditField.ValueChangedFcn = createCallbackFcn(app, @MapScaleEditFieldValueChanged, true);
            app.CBVScaleEditField.Tooltip = {'Upper end of the rCBV map scale'};
            app.CBVScaleEditField.Value = 1;
            app.CBVScaleMaxLabel = uilabel(app.CBVColorMapButtonGroup);
            app.CBVScaleMaxLabel.Text = 'max';
            app.CBVScaleMinLabel = uilabel(app.CBVColorMapButtonGroup);
            app.CBVScaleMinLabel.Text = 'min';
            app.CBVScaleMinEditField = uieditfield(app.CBVColorMapButtonGroup, 'numeric');
            app.CBVScaleMinEditField.Limits = [0 Inf];
            app.CBVScaleMinEditField.ValueDisplayFormat = '%.2f';
            app.CBVScaleMinEditField.ValueChangedFcn = createCallbackFcn(app, @MapScaleMinEditFieldValueChanged, true);
            app.CBVScaleMinEditField.Tooltip = {'Lower end of the rCBV map scale'};
            app.CBVScaleAutoButton = uibutton(app.CBVColorMapButtonGroup, 'push');
            app.CBVScaleAutoButton.ButtonPushedFcn = createCallbackFcn(app, @CBVScaleAutoButtonPushed, true);
            app.CBVScaleAutoButton.Tooltip = {'Automatic rCBV map scale setting'};
            app.CBVScaleAutoButton.Text = 'Auto';
            app.CBFColorMapButtonGroup = uibuttongroup(app.rCBVrCBFTab);
            app.CBFColorMapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CBFColorMapButtonGroupSelectionChanged, true);
            app.CBFColorMapButtonGroup.Title = 'Color map';
            app.CBFgrayButton = uiradiobutton(app.CBFColorMapButtonGroup);
            app.CBFgrayButton.Text = 'gray';
            app.CBFgrayButton.Interpreter = 'html';
            app.CBFlipariButton = uiradiobutton(app.CBFColorMapButtonGroup);
            app.CBFlipariButton.Text = 'lipari';
            app.CBFlipariButton.Interpreter = 'html';
            app.CBFwinterButton = uiradiobutton(app.CBFColorMapButtonGroup);
            app.CBFwinterButton.Text = 'winter';
            app.CBFwinterButton.Interpreter = 'html';
            app.CBFparulaButton = uiradiobutton(app.CBFColorMapButtonGroup);
            app.CBFparulaButton.Text = 'parula';
            app.CBFparulaButton.Interpreter = 'html';
            app.CBFnaviaButton = uiradiobutton(app.CBFColorMapButtonGroup);
            app.CBFnaviaButton.Text = 'navia';
            app.CBFnaviaButton.Interpreter = 'html';
            app.CBFnaviaButton.Value = true;
            app.CBFScaleEditField = uieditfield(app.CBFColorMapButtonGroup, 'numeric');
            app.CBFScaleEditField.Limits = [0.01 Inf];
            app.CBFScaleEditField.ValueDisplayFormat = '%.2f';
            app.CBFScaleEditField.ValueChangedFcn = createCallbackFcn(app, @MapScaleEditFieldValueChanged, true);
            app.CBFScaleEditField.Tooltip = {'Upper end of the rCBF map scale'};
            app.CBFScaleEditField.Value = 1;
            app.CBFScaleMaxLabel = uilabel(app.CBFColorMapButtonGroup);
            app.CBFScaleMaxLabel.Text = 'max';
            app.CBFScaleMinLabel = uilabel(app.CBFColorMapButtonGroup);
            app.CBFScaleMinLabel.Text = 'min';
            app.CBFScaleMinEditField = uieditfield(app.CBFColorMapButtonGroup, 'numeric');
            app.CBFScaleMinEditField.Limits = [0 Inf];
            app.CBFScaleMinEditField.ValueDisplayFormat = '%.2f';
            app.CBFScaleMinEditField.ValueChangedFcn = createCallbackFcn(app, @MapScaleMinEditFieldValueChanged, true);
            app.CBFScaleMinEditField.Tooltip = {'Lower end of the rCBF map scale'};
            app.CBFScaleAutoButton = uibutton(app.CBFColorMapButtonGroup, 'push');
            app.CBFScaleAutoButton.ButtonPushedFcn = createCallbackFcn(app, @CBFScaleAutoButtonPushed, true);
            app.CBFScaleAutoButton.Tooltip = {'Automatic rCBF map scale setting'};
            app.CBFScaleAutoButton.Text = 'Auto';
            app.MTTTTPTab = uitab(app.TabGroup3);
            app.MTTTTPTab.Title = 'MTT / TTP /';
            app.MTTMapFig = uiaxes(app.MTTTTPTab);
            app.MTTMapFig.XTick = [];
            app.MTTMapFig.YTick = [];
            app.TTPMapFig = uiaxes(app.MTTTTPTab);
            app.TTPMapFig.XTick = [];
            app.TTPMapFig.YTick = [];
            app.MTTsLabel = uilabel(app.MTTTTPTab);
            app.MTTsLabel.Tooltip = {'Mean Transit Time (s)'};
            app.MTTsLabel.Text = 'MTT (s)';
            app.TTPsLabel = uilabel(app.MTTTTPTab);
            app.TTPsLabel.Tooltip = {'Time To Peak (s) relative to AIF'};
            app.TTPsLabel.Text = 'TTP (s)';
            app.MapImageSelectionPanel2 = uipanel(app.MTTTTPTab);
            app.MapImageSelectionPanel2.Title = 'Image selection';
            app.MapSlicesSlider2 = uislider(app.MapImageSelectionPanel2);
            app.MapSlicesSlider2.Limits = [1 200];
            app.MapSlicesSlider2.MajorTicks = [];
            app.MapSlicesSlider2.ValueChangedFcn = createCallbackFcn(app, @MapSlicesSlider2ValueChanged, true);
            app.MapSlicesSlider2.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.MapSlicesSlider2.Tooltip = {'Slice number slider'};
            app.MapSlicesSlider2.Value = 1;
            app.MapSlicesEditFieldLabel2 = uilabel(app.MapImageSelectionPanel2);
            app.MapSlicesEditFieldLabel2.Text = 'slice';
            app.MapSlicesEditField2 = uieditfield(app.MapImageSelectionPanel2, 'numeric');
            app.MapSlicesEditField2.Limits = [1 Inf];
            app.MapSlicesEditField2.ValueDisplayFormat = '%.0f';
            app.MapSlicesEditField2.ValueChangedFcn = createCallbackFcn(app, @MapSlicesEditField2ValueChanged, true);
            app.MapSlicesEditField2.Tooltip = {'Slice number'};
            app.MapSlicesEditField2.Value = 1;
            app.MTTImage = uiimage(app.MTTTTPTab);
            app.TTPImage = uiimage(app.MTTTTPTab);
            app.MTTColorMapButtonGroup = uibuttongroup(app.MTTTTPTab);
            app.MTTColorMapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MTTColorMapButtonGroupSelectionChanged, true);
            app.MTTColorMapButtonGroup.Title = 'Color map';
            app.MTTgrayButton = uiradiobutton(app.MTTColorMapButtonGroup);
            app.MTTgrayButton.Text = 'gray';
            app.MTTgrayButton.Interpreter = 'html';
            app.MTTlipariButton = uiradiobutton(app.MTTColorMapButtonGroup);
            app.MTTlipariButton.Text = 'lipari';
            app.MTTlipariButton.Interpreter = 'html';
            app.MTTlipariButton.Value = true;
            app.MTTwinterButton = uiradiobutton(app.MTTColorMapButtonGroup);
            app.MTTwinterButton.Text = 'winter';
            app.MTTwinterButton.Interpreter = 'html';
            app.MTTparulaButton = uiradiobutton(app.MTTColorMapButtonGroup);
            app.MTTparulaButton.Text = 'parula';
            app.MTTparulaButton.Interpreter = 'html';
            app.MTTnaviaButton = uiradiobutton(app.MTTColorMapButtonGroup);
            app.MTTnaviaButton.Text = 'navia';
            app.MTTnaviaButton.Interpreter = 'html';
            app.MTTScaleEditField = uieditfield(app.MTTColorMapButtonGroup, 'numeric');
            app.MTTScaleEditField.Limits = [0.01 Inf];
            app.MTTScaleEditField.ValueDisplayFormat = '%.2f';
            app.MTTScaleEditField.ValueChangedFcn = createCallbackFcn(app, @MapScaleEditFieldValueChanged, true);
            app.MTTScaleEditField.Tooltip = {'Upper end of the MTT map scale'};
            app.MTTScaleEditField.Value = 1;
            app.MTTScaleMaxLabel = uilabel(app.MTTColorMapButtonGroup);
            app.MTTScaleMaxLabel.Text = 'max';
            app.MTTScaleMinLabel = uilabel(app.MTTColorMapButtonGroup);
            app.MTTScaleMinLabel.Text = 'min';
            app.MTTScaleMinEditField = uieditfield(app.MTTColorMapButtonGroup, 'numeric');
            app.MTTScaleMinEditField.Limits = [0 Inf];
            app.MTTScaleMinEditField.ValueDisplayFormat = '%.2f';
            app.MTTScaleMinEditField.ValueChangedFcn = createCallbackFcn(app, @MapScaleMinEditFieldValueChanged, true);
            app.MTTScaleMinEditField.Tooltip = {'Lower end of the MTT map scale'};
            app.MTTScaleAutoButton = uibutton(app.MTTColorMapButtonGroup, 'push');
            app.MTTScaleAutoButton.ButtonPushedFcn = createCallbackFcn(app, @MTTScaleAutoButtonPushed, true);
            app.MTTScaleAutoButton.Tooltip = {'Automatic MTT map scale setting'};
            app.MTTScaleAutoButton.Text = 'Auto';
            app.TTPColorMapButtonGroup = uibuttongroup(app.MTTTTPTab);
            app.TTPColorMapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TTPColorMapButtonGroupSelectionChanged, true);
            app.TTPColorMapButtonGroup.Title = 'Color map';
            app.TTPgrayButton = uiradiobutton(app.TTPColorMapButtonGroup);
            app.TTPgrayButton.Text = 'gray';
            app.TTPgrayButton.Interpreter = 'html';
            app.TTPlipariButton = uiradiobutton(app.TTPColorMapButtonGroup);
            app.TTPlipariButton.Text = 'lipari';
            app.TTPlipariButton.Interpreter = 'html';
            app.TTPlipariButton.Value = true;
            app.TTPwinterButton = uiradiobutton(app.TTPColorMapButtonGroup);
            app.TTPwinterButton.Text = 'winter';
            app.TTPwinterButton.Interpreter = 'html';
            app.TTPparulaButton = uiradiobutton(app.TTPColorMapButtonGroup);
            app.TTPparulaButton.Text = 'parula';
            app.TTPparulaButton.Interpreter = 'html';
            app.TTPnaviaButton = uiradiobutton(app.TTPColorMapButtonGroup);
            app.TTPnaviaButton.Text = 'navia';
            app.TTPnaviaButton.Interpreter = 'html';
            app.TTPScaleEditField = uieditfield(app.TTPColorMapButtonGroup, 'numeric');
            app.TTPScaleEditField.Limits = [0.01 Inf];
            app.TTPScaleEditField.ValueDisplayFormat = '%.2f';
            app.TTPScaleEditField.ValueChangedFcn = createCallbackFcn(app, @MapScaleEditFieldValueChanged, true);
            app.TTPScaleEditField.Tooltip = {'Upper end of the TTP map scale'};
            app.TTPScaleEditField.Value = 1;
            app.TTPScaleMaxLabel = uilabel(app.TTPColorMapButtonGroup);
            app.TTPScaleMaxLabel.Text = 'max';
            app.TTPScaleMinLabel = uilabel(app.TTPColorMapButtonGroup);
            app.TTPScaleMinLabel.Text = 'min';
            app.TTPScaleMinEditField = uieditfield(app.TTPColorMapButtonGroup, 'numeric');
            app.TTPScaleMinEditField.Limits = [0 Inf];
            app.TTPScaleMinEditField.ValueDisplayFormat = '%.2f';
            app.TTPScaleMinEditField.ValueChangedFcn = createCallbackFcn(app, @MapScaleMinEditFieldValueChanged, true);
            app.TTPScaleMinEditField.Tooltip = {'Lower end of the TTP map scale'};
            app.TTPScaleAutoButton = uibutton(app.TTPColorMapButtonGroup, 'push');
            app.TTPScaleAutoButton.ButtonPushedFcn = createCallbackFcn(app, @TTPScaleAutoButtonPushed, true);
            app.TTPScaleAutoButton.Tooltip = {'Automatic TTP map scale setting'};
            app.TTPScaleAutoButton.Text = 'Auto';
            app.rCBVlcK2Tab = uitab(app.TabGroup3);
            app.rCBVlcK2Tab.Title = 'rCBVlc / K2';
            app.CBVlcMapFig = uiaxes(app.rCBVlcK2Tab);
            app.CBVlcMapFig.XTick = [];
            app.CBVlcMapFig.YTick = [];
            app.K2MapFig = uiaxes(app.rCBVlcK2Tab);
            app.K2MapFig.XTick = [];
            app.K2MapFig.YTick = [];
            app.K2Image = uiimage(app.rCBVlcK2Tab);
            app.rCBVlcLabel = uilabel(app.rCBVlcK2Tab);
            app.rCBVlcLabel.Tooltip = {'relative Cerebral Vascular Volume, with leakage correction'};
            app.rCBVlcLabel.Text = 'rCBVlc [-]';
            app.K2Label = uilabel(app.rCBVlcK2Tab);
            app.K2Label.Tooltip = {'leakage rate constant (1/s)'};
            app.K2Label.Text = 'K2 (1/s)';
            app.CBVlcImage = uiimage(app.rCBVlcK2Tab);
            app.MapImageSelectionPanel3 = uipanel(app.rCBVlcK2Tab);
            app.MapImageSelectionPanel3.Title = 'Image selection';
            app.MapSlicesSlider3 = uislider(app.MapImageSelectionPanel3);
            app.MapSlicesSlider3.Limits = [1 200];
            app.MapSlicesSlider3.MajorTicks = [];
            app.MapSlicesSlider3.ValueChangedFcn = createCallbackFcn(app, @MapSlicesSlider3ValueChanged, true);
            app.MapSlicesSlider3.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.MapSlicesSlider3.Tooltip = {'Slice number slider'};
            app.MapSlicesSlider3.Value = 1;
            app.MapSlicesEditFieldLabel3 = uilabel(app.MapImageSelectionPanel3);
            app.MapSlicesEditFieldLabel3.Text = 'slice';
            app.MapSlicesEditField3 = uieditfield(app.MapImageSelectionPanel3, 'numeric');
            app.MapSlicesEditField3.Limits = [1 Inf];
            app.MapSlicesEditField3.ValueDisplayFormat = '%.0f';
            app.MapSlicesEditField3.ValueChangedFcn = createCallbackFcn(app, @MapSlicesEditField3ValueChanged, true);
            app.MapSlicesEditField3.Tooltip = {'Slice number'};
            app.MapSlicesEditField3.Value = 1;
            app.CBVlcColorMapButtonGroup = uibuttongroup(app.rCBVlcK2Tab);
            app.CBVlcColorMapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CBVlcColorMapButtonGroupSelectionChanged, true);
            app.CBVlcColorMapButtonGroup.Title = 'Color map';
            app.CBVlcgrayButton = uiradiobutton(app.CBVlcColorMapButtonGroup);
            app.CBVlcgrayButton.Text = 'gray';
            app.CBVlcgrayButton.Interpreter = 'html';
            app.CBVllipariButton = uiradiobutton(app.CBVlcColorMapButtonGroup);
            app.CBVllipariButton.Text = 'lipari';
            app.CBVllipariButton.Interpreter = 'html';
            app.CBVllipariButton.Value = true;
            app.CBVlcwinterButton = uiradiobutton(app.CBVlcColorMapButtonGroup);
            app.CBVlcwinterButton.Text = 'winter';
            app.CBVlcwinterButton.Interpreter = 'html';
            app.CBVlcparulaButton = uiradiobutton(app.CBVlcColorMapButtonGroup);
            app.CBVlcparulaButton.Text = 'parula';
            app.CBVlcparulaButton.Interpreter = 'html';
            app.CBVlcNaviaButton = uiradiobutton(app.CBVlcColorMapButtonGroup);
            app.CBVlcNaviaButton.Text = 'navia';
            app.CBVlcNaviaButton.Interpreter = 'html';
            app.CBVlcScaleEditField = uieditfield(app.CBVlcColorMapButtonGroup, 'numeric');
            app.CBVlcScaleEditField.Limits = [0.01 Inf];
            app.CBVlcScaleEditField.ValueDisplayFormat = '%.2f';
            app.CBVlcScaleEditField.ValueChangedFcn = createCallbackFcn(app, @MapScaleEditFieldValueChanged, true);
            app.CBVlcScaleEditField.Tooltip = {'Upper end of the rCBVlc map scale'};
            app.CBVlcScaleEditField.Value = 1;
            app.CBVlcScaleMaxLabel = uilabel(app.CBVlcColorMapButtonGroup);
            app.CBVlcScaleMaxLabel.Text = 'max';
            app.CBVlcScaleMinLabel = uilabel(app.CBVlcColorMapButtonGroup);
            app.CBVlcScaleMinLabel.Text = 'min';
            app.CBVlcScaleMinEditField = uieditfield(app.CBVlcColorMapButtonGroup, 'numeric');
            app.CBVlcScaleMinEditField.Limits = [0 Inf];
            app.CBVlcScaleMinEditField.ValueDisplayFormat = '%.2f';
            app.CBVlcScaleMinEditField.ValueChangedFcn = createCallbackFcn(app, @MapScaleMinEditFieldValueChanged, true);
            app.CBVlcScaleMinEditField.Tooltip = {'Lower end of the rCBVlc map scale'};
            app.CBVlcScaleAutoButton = uibutton(app.CBVlcColorMapButtonGroup, 'push');
            app.CBVlcScaleAutoButton.ButtonPushedFcn = createCallbackFcn(app, @CBVlcScaleAutoButtonPushed, true);
            app.CBVlcScaleAutoButton.Tooltip = {'Automatic rCBVlc map scale setting'};
            app.CBVlcScaleAutoButton.Text = 'Auto';
            app.K2ColorMapButtonGroup = uibuttongroup(app.rCBVlcK2Tab);
            app.K2ColorMapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @K2ColorMapButtonGroupSelectionChanged, true);
            app.K2ColorMapButtonGroup.Title = 'Color map';
            app.K2grayButton = uiradiobutton(app.K2ColorMapButtonGroup);
            app.K2grayButton.Text = 'gray';
            app.K2grayButton.Interpreter = 'html';
            app.K2lipariButton = uiradiobutton(app.K2ColorMapButtonGroup);
            app.K2lipariButton.Text = 'lipari';
            app.K2lipariButton.Interpreter = 'html';
            app.K2winterButton = uiradiobutton(app.K2ColorMapButtonGroup);
            app.K2winterButton.Text = 'winter';
            app.K2winterButton.Interpreter = 'html';
            app.K2parulaButton = uiradiobutton(app.K2ColorMapButtonGroup);
            app.K2parulaButton.Text = 'parula';
            app.K2parulaButton.Interpreter = 'html';
            app.K2naviaButton = uiradiobutton(app.K2ColorMapButtonGroup);
            app.K2naviaButton.Text = 'navia';
            app.K2naviaButton.Interpreter = 'html';
            app.K2naviaButton.Value = true;
            app.K2ScaleEditField = uieditfield(app.K2ColorMapButtonGroup, 'numeric');
            app.K2ScaleEditField.Limits = [0.01 Inf];
            app.K2ScaleEditField.ValueDisplayFormat = '%.2f';
            app.K2ScaleEditField.ValueChangedFcn = createCallbackFcn(app, @MapScaleEditFieldValueChanged, true);
            app.K2ScaleEditField.Tooltip = {'Upper end of the K2 map scale'};
            app.K2ScaleEditField.Value = 1;
            app.K2ScaleMaxLabel = uilabel(app.K2ColorMapButtonGroup);
            app.K2ScaleMaxLabel.Text = 'max';
            app.K2ScaleMinLabel = uilabel(app.K2ColorMapButtonGroup);
            app.K2ScaleMinLabel.Text = 'min';
            app.K2ScaleMinEditField = uieditfield(app.K2ColorMapButtonGroup, 'numeric');
            app.K2ScaleMinEditField.Limits = [0 Inf];
            app.K2ScaleMinEditField.ValueDisplayFormat = '%.2f';
            app.K2ScaleMinEditField.ValueChangedFcn = createCallbackFcn(app, @MapScaleMinEditFieldValueChanged, true);
            app.K2ScaleMinEditField.Tooltip = {'Lower end of the K2 map scale'};
            app.K2ScaleAutoButton = uibutton(app.K2ColorMapButtonGroup, 'push');
            app.K2ScaleAutoButton.ButtonPushedFcn = createCallbackFcn(app, @K2ScaleAutoButtonPushed, true);
            app.K2ScaleAutoButton.Tooltip = {'Automatic K2 map scale setting'};
            app.K2ScaleAutoButton.Text = 'Auto';
            app.TabGroup2 = uitabgroup(app.DSCmapUIFigure);
            app.RawImagesTab = uitab(app.TabGroup2);
            app.RawImagesTab.Title = 'Raw images';
            app.RawDataFig = uiaxes(app.RawImagesTab);
            app.RawDataFig.XTick = [];
            app.RawDataFig.YTick = [];
            app.ImageLabel = uilabel(app.RawImagesTab);
            app.ImageLabel.Text = 'Image';
            app.ImageSelectionPanel = uipanel(app.RawImagesTab);
            app.ImageSelectionPanel.Title = 'Image selection';
            app.SlicesEditField = uieditfield(app.ImageSelectionPanel, 'numeric');
            app.SlicesEditField.Limits = [1 Inf];
            app.SlicesEditField.ValueDisplayFormat = '%.0f';
            app.SlicesEditField.ValueChangedFcn = createCallbackFcn(app, @SlicesEditFieldValueChanged, true);
            app.SlicesEditField.Tooltip = {'Slice number'};
            app.SlicesEditField.Value = 1;
            app.SlicesSlider = uislider(app.ImageSelectionPanel);
            app.SlicesSlider.Limits = [1 200];
            app.SlicesSlider.MajorTicks = [];
            app.SlicesSlider.ValueChangedFcn = createCallbackFcn(app, @SlicesSliderValueChanged, true);
            app.SlicesSlider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.SlicesSlider.Tooltip = {'Slice number slider'};
            app.SlicesSlider.Value = 1;
            app.DynamicsEditField = uieditfield(app.ImageSelectionPanel, 'numeric');
            app.DynamicsEditField.Limits = [1 Inf];
            app.DynamicsEditField.ValueDisplayFormat = '%.0f';
            app.DynamicsEditField.ValueChangedFcn = createCallbackFcn(app, @SlicesEditFieldValueChanged, true);
            app.DynamicsEditField.Tooltip = {'Image number'};
            app.DynamicsEditField.Value = 1;
            app.DynamicsSlider = uislider(app.ImageSelectionPanel);
            app.DynamicsSlider.Limits = [1 200];
            app.DynamicsSlider.MajorTicks = [];
            app.DynamicsSlider.ValueChangedFcn = createCallbackFcn(app, @DynamicsSliderValueChanged, true);
            app.DynamicsSlider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.DynamicsSlider.Tooltip = {'Image number slider'};
            app.DynamicsSlider.Value = 1;
            app.SlicesEditFieldLabel = uilabel(app.ImageSelectionPanel);
            app.SlicesEditFieldLabel.Text = 'slice';
            app.DynamicsEditFieldLabel = uilabel(app.ImageSelectionPanel);
            app.DynamicsEditFieldLabel.Text = 'dynamic';
            app.MaskSettingsPanel = uipanel(app.RawImagesTab);
            app.MaskSettingsPanel.Title = 'Mask settings';
            app.ThresholdSlider = uislider(app.MaskSettingsPanel);
            app.ThresholdSlider.Limits = [1 16384];
            app.ThresholdSlider.MajorTicks = [];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.MinorTicks = [1 1001 2001 3001 4001 5001 6001 7001 8001 9001 10001 11001 12001 13001 14001 15001 16384];
            app.ThresholdSlider.Tooltip = {'Mask threshold slider'};
            app.ThresholdSlider.Value = 1;
            app.ErodeButton = uibutton(app.MaskSettingsPanel, 'push');
            app.ErodeButton.ButtonPushedFcn = createCallbackFcn(app, @ErodeButtonPushed, true);
            app.ErodeButton.Tooltip = {'Mask erosion'};
            app.ErodeButton.Text = 'Erode';
            app.DilateButton = uibutton(app.MaskSettingsPanel, 'push');
            app.DilateButton.ButtonPushedFcn = createCallbackFcn(app, @DilateButtonPushed, true);
            app.DilateButton.Tooltip = {'Mask dilation'};
            app.DilateButton.Text = 'Dilate';
            app.CloseButton = uibutton(app.MaskSettingsPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Tooltip = {'Mask closing'};
            app.CloseButton.Text = 'Close';
            app.OpenButton = uibutton(app.MaskSettingsPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Tooltip = {'Mask opening'};
            app.OpenButton.Text = 'Open';
            app.UndoButton = uibutton(app.MaskSettingsPanel, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Tooltip = {'Undo the last erode, dilate, close or open'};
            app.UndoButton.Text = 'Undo';
            app.MaskSizeEditField = uieditfield(app.MaskSettingsPanel, 'numeric');
            app.MaskSizeEditField.Limits = [1 10];
            app.MaskSizeEditField.RoundFractionalValues = 'on';
            app.MaskSizeEditField.ValueDisplayFormat = '%.0f';
            app.MaskSizeEditField.Tooltip = {'Size of erode, dilate, close and open, in pixels'};
            app.MaskSizeEditField.Value = 1;
            app.thresholdEditFieldLabel = uilabel(app.MaskSettingsPanel);
            app.thresholdEditFieldLabel.Text = 'threshold';
            app.thresholdEditField = uieditfield(app.MaskSettingsPanel, 'numeric');
            app.thresholdEditField.Limits = [0 Inf];
            app.thresholdEditField.ValueDisplayFormat = '%.0f';
            app.thresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.thresholdEditField.Tooltip = {'Mask threshold'};
            app.ImageWindowLevelPanel = uipanel(app.RawImagesTab);
            app.ImageWindowLevelPanel.Title = 'Image window/level';
            app.WindowEditField = uieditfield(app.ImageWindowLevelPanel, 'numeric');
            app.WindowEditField.Limits = [0 Inf];
            app.WindowEditField.ValueDisplayFormat = '%.0f';
            app.WindowEditField.ValueChangedFcn = createCallbackFcn(app, @WindowEditFieldValueChanged, true);
            app.WindowEditField.Tooltip = {'Window'};
            app.WindowSlider = uislider(app.ImageWindowLevelPanel);
            app.WindowSlider.Limits = [0 32768];
            app.WindowSlider.MajorTicks = [];
            app.WindowSlider.ValueChangedFcn = createCallbackFcn(app, @WindowSliderValueChanged, true);
            app.WindowSlider.MinorTicks = [0 2048 4096 6144 8192 10240 12288 14336 16384 18432 20480 22528 24576 26624 28672 30720 32768];
            app.WindowSlider.Tooltip = {'Window'};
            app.WindowSlider.Value = 16384;
            app.LevelEditField = uieditfield(app.ImageWindowLevelPanel, 'numeric');
            app.LevelEditField.Limits = [0 Inf];
            app.LevelEditField.ValueDisplayFormat = '%.0f';
            app.LevelEditField.ValueChangedFcn = createCallbackFcn(app, @LevelEditFieldValueChanged, true);
            app.LevelEditField.Tooltip = {'Level'};
            app.LevelSlider = uislider(app.ImageWindowLevelPanel);
            app.LevelSlider.Limits = [0 32768];
            app.LevelSlider.MajorTicks = [];
            app.LevelSlider.ValueChangedFcn = createCallbackFcn(app, @LevelSliderValueChanged, true);
            app.LevelSlider.MinorTicks = [0 2048 4096 6144 8192 10240 12288 14336 16384 18432 20480 22528 24576 26624 28672 30720 32768];
            app.LevelSlider.Tooltip = {'Level'};
            app.LevelSlider.Value = 16384;
            app.LevelEditFieldLabel = uilabel(app.ImageWindowLevelPanel);
            app.LevelEditFieldLabel.Text = 'L';
            app.WindowEditFieldLabel = uilabel(app.ImageWindowLevelPanel);
            app.WindowEditFieldLabel.Text = 'W';
            app.AutoLWButton = uibutton(app.ImageWindowLevelPanel, 'state');
            app.AutoLWButton.ValueChangedFcn = createCallbackFcn(app, @AutoLWButtonValueChanged, true);
            app.AutoLWButton.Tooltip = {'Automatic window and level setting'};
            app.AutoLWButton.Text = 'Auto';
            app.AutoLWButton.Value = true;
            app.RawImage = uiimage(app.RawImagesTab);
            app.RawImage.ImageClickedFcn = createCallbackFcn(app, @RawImageClicked, true);
            app.SequenceParametersTab = uitab(app.TabGroup2);
            app.SequenceParametersTab.Title = 'Sequence parameters';
            app.DicomFolderViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.DicomFolderViewFieldLabel.Text = 'DCM folder';
            app.DicomFolderViewField = uieditfield(app.SequenceParametersTab, 'text');
            app.DicomFolderViewField.Editable = 'off';
            app.DicomFolderViewField.Tooltip = {'DICOM data folder'};
            app.TRViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.TRViewFieldLabel.Text = 'TR (ms)';
            app.TRViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.TRViewField.Limits = [0 Inf];
            app.TRViewField.Editable = 'off';
            app.TRViewField.Tooltip = {'Repetition time'};
            app.TEViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.TEViewFieldLabel.Text = 'TE (ms)';
            app.TEViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.TEViewField.Limits = [0 Inf];
            app.TEViewField.Editable = 'off';
            app.TEViewField.Tooltip = {'Echo time'};
            app.FlipAngleViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.FlipAngleViewFieldLabel.Text = 'flip angle';
            app.FlipAngleViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.FlipAngleViewField.Limits = [0 Inf];
            app.FlipAngleViewField.Editable = 'off';
            app.FlipAngleViewField.Tooltip = {'Flip angle'};
            app.NRDynamicsViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.NRDynamicsViewFieldLabel.Text = '#dynamics';
            app.NRDynamicsViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.NRDynamicsViewField.Limits = [0 Inf];
            app.NRDynamicsViewField.Editable = 'off';
            app.NRDynamicsViewField.Tooltip = {'Number of images'};
            app.AspectRatioViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.AspectRatioViewFieldLabel.Text = 'aspect ratio';
            app.AspectRatioViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.AspectRatioViewField.Limits = [0.25 4];
            app.AspectRatioViewField.ValueDisplayFormat = '%.3f';
            app.AspectRatioViewField.Editable = 'off';
            app.AspectRatioViewField.Tooltip = {'Image aspect ratio'};
            app.AspectRatioViewField.Value = 1;
            app.FOVViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.FOVViewFieldLabel.Text = 'FOV (mm)';
            app.FOVViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.FOVViewField.Limits = [0 Inf];
            app.FOVViewField.Editable = 'off';
            app.FOVViewField.Tooltip = {'Field-of-view'};
            app.dimxViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.dimxViewFieldLabel.Text = 'dim X';
            app.dimxViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.dimxViewField.Limits = [0 Inf];
            app.dimxViewField.ValueDisplayFormat = '%.0f';
            app.dimxViewField.Editable = 'off';
            app.dimxViewField.Tooltip = {'Readout matrix size'};
            app.dimyViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.dimyViewFieldLabel.Text = 'dim Y';
            app.dimyViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.dimyViewField.Limits = [0 Inf];
            app.dimyViewField.ValueDisplayFormat = '%.0f';
            app.dimyViewField.Editable = 'off';
            app.dimyViewField.Tooltip = {'Phase-encoding matrix size'};
            app.SlicesViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.SlicesViewFieldLabel.Text = '#slices';
            app.SlicesViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.SlicesViewField.Limits = [0 Inf];
            app.SlicesViewField.ValueDisplayFormat = '%.0f';
            app.SlicesViewField.Editable = 'off';
            app.SlicesViewField.Tooltip = {'Number of slices'};
            app.SliceThicknessViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.SliceThicknessViewFieldLabel.Text = 'slice thickness (mm)';
            app.SliceThicknessViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.SliceThicknessViewField.Limits = [0 Inf];
            app.SliceThicknessViewField.Editable = 'off';
            app.SliceThicknessViewField.Tooltip = {'Slice thickness'};
            app.AppSettingsTab = uitab(app.TabGroup2);
            app.AppSettingsTab.Title = 'App settings';
            app.SoundsLabel = uilabel(app.AppSettingsTab);
            app.SoundsLabel.Text = 'Sounds';
            app.AutoSoundsCheckBox = uicheckbox(app.AppSettingsTab);
            app.AutoSoundsCheckBox.Tooltip = {'Warning and error sounds on or off'};
            app.AutoSoundsCheckBox.Text = '';
            app.AutoSoundsCheckBox.Value = true;
            app.AutoSoundsCheckBoxLabel = uilabel(app.AppSettingsTab);
            app.AutoSoundsCheckBoxLabel.Tooltip = {'Warning and error sounds on or off'};
            app.AutoSoundsCheckBoxLabel.Text = 'sounds on/off';
            app.ColorsLabel = uilabel(app.AppSettingsTab);
            app.ColorsLabel.Text = 'Colors';
            app.ColorsButtonGroup = uibuttongroup(app.AppSettingsTab);
            app.ColorsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColorsButtonGroupSelectionChanged, true);
            app.ColorsButtonGroup.Tooltip = {'App color scheme selection'};
            app.ClassicButton = uiradiobutton(app.ColorsButtonGroup);
            app.ClassicButton.Text = 'classic';
            app.ClassicButton.Interpreter = 'html';
            app.BlackWhiteButton = uiradiobutton(app.ColorsButtonGroup);
            app.BlackWhiteButton.Text = 'black/white';
            app.BlackWhiteButton.Interpreter = 'html';
            app.BlackWhiteButton.Value = true;
            app.TabGroup4 = uitabgroup(app.DSCmapUIFigure);
            app.ArterialInputFunctionTab = uitab(app.TabGroup4);
            app.ArterialInputFunctionTab.Title = 'Arterial input function';
            app.ArterialInputFunctionPlot = uiaxes(app.ArterialInputFunctionTab);
            xlabel(app.ArterialInputFunctionPlot, 'time (s)')
            ylabel(app.ArterialInputFunctionPlot, 'signal')
            app.ArterialInputFunctionPlot.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.ConcInputFunctionPlot = uiaxes(app.ArterialInputFunctionTab);
            xlabel(app.ConcInputFunctionPlot, 'time (s)')
            ylabel(app.ConcInputFunctionPlot, 'AIF')
            app.XViewFieldLabel = uilabel(app.ArterialInputFunctionTab);
            app.XViewFieldLabel.Text = 'X';
            app.XViewField = uieditfield(app.ArterialInputFunctionTab, 'numeric');
            app.XViewField.ValueDisplayFormat = '%.0f';
            app.XViewField.Editable = 'off';
            app.XViewField.Tooltip = {'X-coordinate'};
            app.YViewFieldLabel = uilabel(app.ArterialInputFunctionTab);
            app.YViewFieldLabel.Text = 'Y';
            app.YViewField = uieditfield(app.ArterialInputFunctionTab, 'numeric');
            app.YViewField.ValueDisplayFormat = '%.0f';
            app.YViewField.Editable = 'off';
            app.YViewField.Tooltip = {'Y-coordinate'};
            app.FitParametersTab = uitab(app.TabGroup4);
            app.FitParametersTab.Title = 'Fit parameters';
            app.DataSelectionPanel = uipanel(app.FitParametersTab);
            app.DataSelectionPanel.Title = 'Data selection';
            app.FirstDynamicEditFieldLabel = uilabel(app.DataSelectionPanel);
            app.FirstDynamicEditFieldLabel.Enable = 'off';
            app.FirstDynamicEditFieldLabel.Text = 'First dynamic';
            app.FirstDynamicEditField = uieditfield(app.DataSelectionPanel, 'numeric');
            app.FirstDynamicEditField.Limits = [1 40];
            app.FirstDynamicEditField.ValueChangedFcn = createCallbackFcn(app, @FirstDynamicEditFieldValueChanged, true);
            app.FirstDynamicEditField.Enable = 'off';
            app.FirstDynamicEditField.Tooltip = {'First dynamic (used to discard transient)'};
            app.FirstDynamicEditField.Value = 15;
            app.AIFSettingsPanel = uipanel(app.FitParametersTab);
            app.AIFSettingsPanel.Title = 'AIF settings';
            app.LongAxisEditFieldLabel = uilabel(app.AIFSettingsPanel);
            app.LongAxisEditFieldLabel.Enable = 'off';
            app.LongAxisEditFieldLabel.Text = 'ROI y axis (%)';
            app.LongAxisEditField = uieditfield(app.AIFSettingsPanel, 'numeric');
            app.LongAxisEditField.Limits = [5 100];
            app.LongAxisEditField.ValueDisplayFormat = '%.0f';
            app.LongAxisEditField.Enable = 'off';
            app.LongAxisEditField.Tooltip = {'ROI vertical axis (%)'};
            app.LongAxisEditField.Value = 15;
            app.ShortAxisEditFieldLabel = uilabel(app.AIFSettingsPanel);
            app.ShortAxisEditFieldLabel.Enable = 'off';
            app.ShortAxisEditFieldLabel.Text = 'ROI x axis (%)';
            app.ShortAxisEditField = uieditfield(app.AIFSettingsPanel, 'numeric');
            app.ShortAxisEditField.Limits = [5 100];
            app.ShortAxisEditField.ValueDisplayFormat = '%.0f';
            app.ShortAxisEditField.Enable = 'off';
            app.ShortAxisEditField.Tooltip = {'ROI horizontal axis (%)'};
            app.ShortAxisEditField.Value = 15;
            app.MaxAIFpixelsEditFieldLabel = uilabel(app.AIFSettingsPanel);
            app.MaxAIFpixelsEditFieldLabel.Enable = 'off';
            app.MaxAIFpixelsEditFieldLabel.Text = 'Max # AIF pixels';
            app.MaxAIFpixelsEditField = uieditfield(app.AIFSettingsPanel, 'numeric');
            app.MaxAIFpixelsEditField.Limits = [5 20];
            app.MaxAIFpixelsEditField.ValueDisplayFormat = '%.0f';
            app.MaxAIFpixelsEditField.Enable = 'off';
            app.MaxAIFpixelsEditField.Tooltip = {'Maximum allowed AIF pixels in search area'};
            app.MaxAIFpixelsEditField.Value = 10;
            app.QuadraticCheckBox = uicheckbox(app.AIFSettingsPanel);
            app.QuadraticCheckBox.Tooltip = {'Linear or quadratic (checked) R2* with contrast agent concentration '};
            app.QuadraticCheckBox.Enable = 'off';
            app.QuadraticCheckBox.Text = '';
            app.QuadraticCheckBoxLabel = uilabel(app.AIFSettingsPanel);
            app.QuadraticCheckBoxLabel.Enable = 'off';
            app.QuadraticCheckBoxLabel.Tooltip = {'Linear or quadratic (checked) R2* with contrast agent concentration '};
            app.QuadraticCheckBoxLabel.Text = 'Quadratic R2* [CA]';
            app.CBFfitMethodButtonGroup = uibuttongroup(app.FitParametersTab);
            app.CBFfitMethodButtonGroup.Tooltip = {''};
            app.CBFfitMethodButtonGroup.Title = 'DSC fit parameters';
            app.SVDButton = uiradiobutton(app.CBFfitMethodButtonGroup);
            app.SVDButton.Enable = 'off';
            app.SVDButton.Tooltip = {'Deconvolution method'};
            app.SVDButton.Text = '';
            app.SVDButton.Interpreter = 'html';
            app.SVDButton.Value = true;
            app.cSVDButton = uiradiobutton(app.CBFfitMethodButtonGroup);
            app.cSVDButton.Enable = 'off';
            app.cSVDButton.Tooltip = {'Deconvolution method'};
            app.cSVDButton.Text = '';
            app.cSVDButton.Interpreter = 'html';
            app.oSVDButton = uiradiobutton(app.CBFfitMethodButtonGroup);
            app.oSVDButton.Enable = 'off';
            app.oSVDButton.Tooltip = {'Deconvolution method'};
            app.oSVDButton.Text = '';
            app.oSVDButton.Interpreter = 'html';
            app.KhEditFieldLabel = uilabel(app.CBFfitMethodButtonGroup);
            app.KhEditFieldLabel.Enable = 'off';
            app.KhEditFieldLabel.Text = 'Kh';
            app.KhEditField = uieditfield(app.CBFfitMethodButtonGroup, 'numeric');
            app.KhEditField.Limits = [0.1 2];
            app.KhEditField.ValueDisplayFormat = '%.2f';
            app.KhEditField.Enable = 'off';
            app.KhEditField.Tooltip = {'Kh = hematocrit factor in the capillary system (typically 0.67). When set to 1, relative estimates of CBV are obtained.'};
            app.KhEditField.Value = 1;
            app.RhoEditFieldLabel = uilabel(app.CBFfitMethodButtonGroup);
            app.RhoEditFieldLabel.Enable = 'off';
            app.RhoEditFieldLabel.Text = 'ρ';
            app.RhoEditField = uieditfield(app.CBFfitMethodButtonGroup, 'numeric');
            app.RhoEditField.Limits = [0.1 2];
            app.RhoEditField.ValueDisplayFormat = '%.2f';
            app.RhoEditField.Enable = 'off';
            app.RhoEditField.Tooltip = {'Rho represents the density of brain tissue (typically 1 g/ml tissue), by default it is set to one, in this case, relative estimates of the CBV parameter are obtained.'};
            app.RhoEditField.Value = 1;
            app.SVDLabel = uilabel(app.CBFfitMethodButtonGroup);
            app.SVDLabel.Enable = 'off';
            app.SVDLabel.Tooltip = {'Convolution method'};
            app.SVDLabel.Text = 'SVD';
            app.cSVDLabel = uilabel(app.CBFfitMethodButtonGroup);
            app.cSVDLabel.Enable = 'off';
            app.cSVDLabel.Tooltip = {'Convolution method'};
            app.cSVDLabel.Text = 'cSVD';
            app.oSVDLabel = uilabel(app.CBFfitMethodButtonGroup);
            app.oSVDLabel.Enable = 'off';
            app.oSVDLabel.Tooltip = {'Convolution method'};
            app.oSVDLabel.Text = 'oSVD';
            app.FitEvaluationTab = uitab(app.TabGroup4);
            app.FitEvaluationTab.Title = 'Fit evaluation';
            app.ResidualPlot = uiaxes(app.FitEvaluationTab);
            xlabel(app.ResidualPlot, 'time (s)')
            ylabel(app.ResidualPlot, 'residual')
            app.ConcentrationPlot = uiaxes(app.FitEvaluationTab);
            xlabel(app.ConcentrationPlot, 'time (s)')
            ylabel(app.ConcentrationPlot, 'conc')
            app.Hyperlink = uihyperlink(app.DSCmapUIFigure);
            app.Hyperlink.URL = 'https://github.com/Moby1971/';
            app.Hyperlink.Text = 'Github';
            % Show the figure after all components are created
            app.DSCmapUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DSCmapp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.DSCmapUIFigure)

                % Execute the startup function
                runStartupFcn(app, @StartupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.DSCmapUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DSCmapUIFigure)
        end
    end
end